classdef SetupSkyLOSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                      matlab.ui.Figure
        Menu_File                   matlab.ui.container.Menu
        Menu_ShowPeriodMax          matlab.ui.container.Menu
        Menu_ComputePermissibleLoc  matlab.ui.container.Menu
        Menu_Quit                   matlab.ui.container.Menu
        Menu_Help                   matlab.ui.container.Menu
        Menu_License                matlab.ui.container.Menu
        Menu_Colourmap              matlab.ui.container.Menu
        Menu_Docs                   matlab.ui.container.Menu
        Menu_Update                 matlab.ui.container.Menu
        Grid_Main                   matlab.ui.container.GridLayout
        Grid_Inputs                 matlab.ui.container.GridLayout
        Panel_Time                  matlab.ui.container.Panel
        Grid_Time                   matlab.ui.container.GridLayout
        Time_Date_Label             matlab.ui.control.Label
        Time_Date                   matlab.ui.control.DatePicker
        Time_Ho_Label               matlab.ui.control.Label
        Time_Ho                     matlab.ui.control.Spinner
        Time_Mi_Label               matlab.ui.control.Label
        Time_Mi                     matlab.ui.control.Spinner
        Time_UTC_Label              matlab.ui.control.Label
        Time_UTC                    matlab.ui.control.Spinner
        Time_Source                 matlab.ui.control.CheckBox
        Time_Reset                  matlab.ui.control.Button
        Panel_Loc                   matlab.ui.container.Panel
        Grid_Loc                    matlab.ui.container.GridLayout
        Loc_Lat_Label               matlab.ui.control.Label
        Loc_Lat                     matlab.ui.control.Spinner
        Loc_LatHemi                 matlab.ui.control.DropDown
        Loc_Lon_Label               matlab.ui.control.Label
        Loc_Lon                     matlab.ui.control.Spinner
        Loc_LonHemi                 matlab.ui.control.DropDown
        Loc_OtherCompute            matlab.ui.control.Button
        Panel_Sky                   matlab.ui.container.Panel
        Grid_Sky                    matlab.ui.container.GridLayout
        Sky_Source                  matlab.ui.control.DropDown
        Panel_Fun                   matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        Fun_Run                     matlab.ui.control.Button
        Fun_Clear                   matlab.ui.control.Button
        Panel_Stats                 matlab.ui.container.Panel
        Grid_Stats                  matlab.ui.container.GridLayout
        Stats_ToPlot_Label          matlab.ui.control.Label
        Stats_ToPlot                matlab.ui.control.DropDown
        Stats_ctr_Label             matlab.ui.control.Label
        Stats_ctr                   matlab.ui.control.DropDown
        Stats_var_Label             matlab.ui.control.Label
        Stats_var                   matlab.ui.control.DropDown
        Panel_Cam                   matlab.ui.container.Panel
        Grid_Cam                    matlab.ui.container.GridLayout
        Cam_Source_Label            matlab.ui.control.Label
        Cam_Source                  matlab.ui.control.DropDown
        Cam_Pitch_Label             matlab.ui.control.Label
        Cam_Pitch                   matlab.ui.control.Spinner
        Cam_Width_Label             matlab.ui.control.Label
        Cam_Width                   matlab.ui.control.Spinner
        Cam_Focal_Label             matlab.ui.control.Label
        Cam_Focal                   matlab.ui.control.Spinner
        Cam_Height_Label            matlab.ui.control.Label
        Cam_Height                  matlab.ui.control.Spinner
        Grid_Outputs                matlab.ui.container.GridLayout
        Panel_Results               matlab.ui.container.Panel
        Panel_Interactivity         matlab.ui.container.Panel
        Grid_Interactivity          matlab.ui.container.GridLayout
        IntEdit_Plume_Label         matlab.ui.control.Label
        IntEdit_Plume               matlab.ui.control.NumericEditField
        IntEdit_Wind_Label          matlab.ui.control.Label
        IntEdit_Wind                matlab.ui.control.NumericEditField
        IntEdit_Cont_Label          matlab.ui.control.Label
        IntEdit_Cont                matlab.ui.control.NumericEditField
        IntEdit_Time_Label          matlab.ui.control.Label
        IntEdit_Time                matlab.ui.control.EditField
        IntSlide_Plume              matlab.ui.control.Slider
        IntSlide_Wind               matlab.ui.control.Slider
        IntSlide_Cont               matlab.ui.control.Slider
        IntSlide_Time               matlab.ui.control.Slider
        Grid_Meta                   matlab.ui.container.GridLayout
        Panel_Sun                   matlab.ui.container.Panel
        Grid_Sun                    matlab.ui.container.GridLayout
        Sun_Rise_Label              matlab.ui.control.Label
        Sun_Rise                    matlab.ui.control.Label
        Sun_Set_Label               matlab.ui.control.Label
        Sun_Set                     matlab.ui.control.Label
        Sun_Azi_Label               matlab.ui.control.Label
        Sun_Azi                     matlab.ui.control.Label
        Sun_Zen_Label               matlab.ui.control.Label
        Sun_Zen                     matlab.ui.control.Label
        Panel_Cursor                matlab.ui.container.Panel
        Grid_Cursor                 matlab.ui.container.GridLayout
        Current_Label               matlab.ui.control.Label
        Selected_Label              matlab.ui.control.Label
        Cursor_Azi_Label            matlab.ui.control.Label
        Current_Azi                 matlab.ui.control.Label
        Cursor_Inc_Label            matlab.ui.control.Label
        Current_Inc                 matlab.ui.control.Label
        Cursor_SunTheta_Label       matlab.ui.control.Label
        Current_SunTheta            matlab.ui.control.Label
        Selected_SunTheta           matlab.ui.control.Label
        Cursor_Dat_Label            matlab.ui.control.Label
        Current_Dat                 matlab.ui.control.Label
        Selected_Dat                matlab.ui.control.Label
        Selected_Inc                matlab.ui.control.Spinner
        Selected_Azi                matlab.ui.control.Spinner
        Panel_PlotVis               matlab.ui.container.Panel
        Grid_PlotVis                matlab.ui.container.GridLayout
        PlotVis_Sun                 matlab.ui.control.StateButton
        PlotVis_Con                 matlab.ui.control.StateButton
        PlotVis_Cam                 matlab.ui.control.StateButton
        PlotVis_Win                 matlab.ui.control.StateButton
        Grid_Images                 matlab.ui.container.GridLayout
        Im_Carleton                 matlab.ui.control.Image
        Im_EERL                     matlab.ui.control.Image
        Im_FlareNet                 matlab.ui.control.Image
        Grid_Utilities              matlab.ui.container.GridLayout
        Maximizer_Button            matlab.ui.control.Button
        Positioner_Button           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Ax_Results      % Results plotting axis
        Cb_Results      % Results plotting colorbar
        colors          % Structure containing plotting colours
        folder_base     % Base folder for loading of data
        ismax           % Boolean for if the plotted data is the period max
        ismax_allgroup  % Boolean for if the period max data is over sky groups
        plotdata        % Structure containing plotted data
        sky             % Structure containing uncertainty data for given sky class
        status          % Structure containing the status of the application
        sunpos          % Structure containing the solar position of the day and moment
        vers            % Software version
    end
    
    properties (Access = public)
        DialogApp       % Dialog Application
        DialogCancelled % Flag for if the Dialog App was cancelled
    end
    
    methods (Access = private)
        
        function sub_UpdateAxisPosition(app)
            % This function updates the position of the axis to support
            % custom impixelinfo functionality
            
            % Ensure drawn
            drawnow
            
            % =========================================================== %
            % Get horizontal dimensions of panel
            num_units = sum(cellfun(@(x) str2double(x(1 : end - 1)), app.Grid_Main.ColumnWidth));
            siz_total = app.Figure.Position(3) - sum(app.Grid_Main.Padding([1 3])) - app.Grid_Main.ColumnSpacing;
            siz_units = siz_total / num_units;
            grid_main_left = app.Grid_Main.Padding(3) + siz_units + app.Grid_Main.ColumnSpacing;
            panl = grid_main_left + app.Grid_Outputs.Padding(1);
            panw = app.Panel_Results.Position(3);
            
            % =========================================================== %
            % Get vertical dimensions of panel
            num_units = sum(cellfun(@(x) str2double(x(1 : end - 1)), app.Grid_Outputs.RowHeight));
            siz_total = app.Figure.Position(4) - sum(app.Grid_Main.Padding([2 4])) - sum(app.Grid_Outputs.Padding([2 4])) - app.Grid_Outputs.RowSpacing * 3;
            siz_units = siz_total / num_units;
            panb = app.Grid_Main.Padding(2) + app.Grid_Outputs.Padding(2) + app.Grid_Outputs.RowSpacing * 3 + siz_units * sum(cellfun(@(x) str2double(x(1 : end - 1)), app.Grid_Outputs.RowHeight(2 : end)));
            panh = app.Panel_Results.InnerPosition(4);
            
            % =========================================================== %
            % Update axis position and reset fontsize of axis and labels
            axpos = [panl + 0.05 * panw, panb + 0.02 * panh, 0.9 * panw, 0.96 * panh];
            app.Ax_Results.Position = axpos;
            drawnow
            app.Ax_Results.XLabel.FontSize = 12;
            app.Ax_Results.YLabel.FontSize = 12;
            app.Ax_Results.FontSize = 10;
        end
        
        function sub_GetStatsAtSolarZenith(app)
            % This subroutine takes the current dataset and interpolates in the solar
            % zenith dimension to get statistics for the given sun position
            
            % Get resolved sun azimuth
            app.plotdata.a_sun = 180 - (360 - abs(app.plotdata.a_cam - app.sunpos.CurrentAzi)) .* (abs(app.plotdata.a_cam - app.sunpos.CurrentAzi) > 180) - abs(app.plotdata.a_cam - app.sunpos.CurrentAzi) .* (abs(app.plotdata.a_cam - app.sunpos.CurrentAzi) <= 180);
            
            % Get resolved sun scattering angle
            app.plotdata.t_sun = real(acosd(cosd(app.sunpos.CurrentZen) .* sind(app.plotdata.b_cam) + sind(app.sunpos.CurrentZen) .* cosd(app.plotdata.b_cam) .* cosd(app.sunpos.CurrentAzi - app.plotdata.a_cam)));
            
            % Perform cubic hermite interpolation =========================
            
            % Pull data
            dat = cat(5, app.sky.data.ave, app.sky.data.mad, app.sky.data.prc, app.sky.data.sig);
            
            % Peform interpolation as needed
            idx_eq = find(abs(app.sky.zv - app.sunpos.CurrentZen) < 1e-6);
            if ~isempty(idx_eq) % Interpolation not needed
                dat = dat(:, :, idx_eq, :, :);
                
            else % Interpolation needed
                % Get k-index and data used regardless of index
                k = find(app.sky.zv < app.sunpos.CurrentZen, 1, 'last');
                pk = dat(:, :, k, :, :);
                pkp1 = dat(:, :, k + 1, :, :);
                zk = app.sky.zv(k);
                zkp1 = app.sky.zv(k + 1);
                
                % Get slope before point
                if k == 1 % Forward difference for mk
                    mk = (pkp1 - pk) / (zkp1 - zk);
                    
                else % central difference about pk
                    pkm1 = dat(:, :, k - 1, :, :);
                    zkm1 = app.sky.zv(k - 1);
                    mk = 0.5 * ((pkp1 - pk) / (zkp1 - zk) + (pk - pkm1) / (zk - zkm1));
                end
                
                % Get slope after point
                if k == (length(app.sky.zv) - 1) % Backwards different for mkp1
                    mkp1 = (pkp1 - pk) / (zkp1 - zk);
                    
                else
                    pkp2 = dat(:, :, k + 2, :, :);
                    zkp2 = app.sky.zv(k + 2);
                    mkp1 = 0.5 * ((pkp2 - pkp1) / (zkp2 - zkp1) + (pkp1 - pk) / (zkp1 - zk));
                end
                
                % Compute position
                t = (app.sunpos.CurrentZen - zk) / (zkp1 - zk);
                
                % Compute Hermite bases
                h00 = polyval([+2 -3 0 1], t);
                h10 = polyval([+1 -2 1 0], t);
                h01 = polyval([-2 +3 0 0], t);
                h11 = polyval([+1 -1 0 0], t);
                
                % Perform interp
                dat = h00 .* pk + h10 * (zkp1 - zk) .* mk + h01 .* pkp1 + h11 .* (zkp1 - zk) .* mkp1;
                
            end
            dat = squeeze(dat);
            tmp_ave = dat(:, :, :, 1);
            tmp_mad = dat(:, :, :, 2);
            tmp_prc = dat(:, :, :, 3 : 5);
            tmp_sig = dat(:, :, :, 6);
            clear dat
            
            % Parse central tendency data
            app.plotdata.ctr{1, 1} = tmp_ave;
            [~, idx] = min(abs(app.sky.data.P - 50));
            app.plotdata.ctr{2, 1} = tmp_prc(:, :, :, idx);
            
            % Parse variability data
            app.plotdata.var{1, 1} = -tmp_sig;
            app.plotdata.var{1, 2} = tmp_sig;
            [~, idx] = min(abs(app.sky.data.P - 2.5)); % 95%
            app.plotdata.var{2, 1} = tmp_prc(:, :, :, idx);
            app.plotdata.var{2, 2} = tmp_prc(:, :, :, length(app.sky.data.P) - idx + 1);
            app.plotdata.var{3, 1} = -tmp_mad;
            app.plotdata.var{3, 2} = tmp_mad;
        end
        
        function sub_Plot_UpdateImage(app)
            % This subroutine updates the image (uncertainty) data
            
            % Obtain image data
            ctr = app.plotdata.ctr{app.Stats_ctr.Value};
            switch app.Stats_ToPlot.Items{app.Stats_ToPlot.Value}
                case 'Absolute Value: Central Tendency'
                    app.plotdata.cdata = ctr * 1000;
                case 'Relative Uncertainty: Coefficient of Variation'
                    sig_num = 2 * [1, norminv(0.975), norminv(0.75)];
                    sig_equiv = (app.plotdata.var{app.Stats_var.Value, 2} - app.plotdata.var{app.Stats_var.Value, 1}) / sig_num(app.Stats_var.Value);
                    app.plotdata.cdata = sig_equiv ./ ctr * 100;
                case 'Relative Uncertainty: 95% Confidence Interval (Tail-Averaged)'
                    app.plotdata.cdata = (app.plotdata.var{app.Stats_var.Value, 2} - app.plotdata.var{app.Stats_var.Value, 1}) ./ ctr / 2 * 100;
                case 'Relative Uncertainty: 95% Confidence Interval (Lower Tail)'
                    app.plotdata.cdata = (1 - app.plotdata.var{app.Stats_var.Value, 1} ./ ctr) * 100;
                case 'Relative Uncertainty: 95% Confidence Interval (Upper Tail)'
                    app.plotdata.cdata = (app.plotdata.var{app.Stats_var.Value, 2} ./ ctr - 1) * 100;
            end
            
            % Peform interpolation over transmittance as needed (dim 3 of 3)
            cdat = app.plotdata.cdata;
            idx_eq = find(abs(app.sky.tv - app.IntSlide_Plume.Value) < 1e-6);
            if ~isempty(idx_eq) % Interpolation not needed
                cdat = cdat(:, :, idx_eq);
                
            else % Interpolation needed
                % Get k-index and data used regardless of index
                k = find(app.sky.tv < app.IntSlide_Plume.Value, 1, 'last');
                pk = cdat(:, :, k);
                pkp1 = cdat(:, :, k + 1);
                tk = app.sky.tv(k);
                tkp1 = app.sky.tv(k + 1);
                
                % Get slope before point
                if k == 1 % Forward difference for mk
                    mk = (pkp1 - pk) / (tkp1 - tk);
                    
                else % central difference about pk
                    pkm1 = cdat(:, :, k - 1);
                    tkm1 = app.sky.tv(k - 1);
                    mk = 0.5 * ((pkp1 - pk) / (tkp1 - tk) + (pk - pkm1) / (tk - tkm1));
                end
                
                % Get slope after point
                if k == (length(app.sky.tv) - 1) % Backwards different for mkp1
                    mkp1 = (pkp1 - pk) / (tkp1 - tk);
                    
                else
                    pkp2 = cdat(:, :, k + 2);
                    tkp2 = app.sky.tv(k + 2);
                    mkp1 = 0.5 * ((pkp2 - pkp1) / (tkp2 - tkp1) + (pkp1 - pk) / (tkp1 - tk));
                end
                
                % Compute position
                t = (app.IntSlide_Plume.Value - tk) / (tkp1 - tk);
                
                % Compute Hermite bases
                h00 = polyval([+2 -3 0 1], t);
                h10 = polyval([+1 -2 1 0], t);
                h01 = polyval([-2 +3 0 0], t);
                h11 = polyval([+1 -1 0 0], t);
                
                % Perform interp
                cdat = h00 .* pk + h10 * (tkp1 - tk) .* mk + h01 .* pkp1 + h11 .* (tkp1 - tk) .* mkp1;
            end
            
            % Get image on axes and filter
            cdat = interp2(app.sky.av, app.sky.bv, cdat, app.plotdata.a_sun, app.plotdata.b_cam, 'makima');
            cdat = imfilter(cdat, ones(11) / 11 ^ 2, 'replicate');
            cdat(isnan(cdat)) = inf;
            
            % Show image
            app.plotdata.h_image.CData = cdat;
            app.plotdata.h_image.Visible = 'on';
            
            % Update colorbar
            sub_Plot_UpdateColorbar(app)
            sub_Plot_UpdateContour(app)
            
            % Update selected point text (if shown)
            if ~strcmp(app.Selected_Dat.Text, '- - - - -')
                xidx = app.Selected_Azi.Value == app.plotdata.h_image.XData;
                yidx = app.Selected_Inc.Value == app.plotdata.h_image.YData;
                
                % Solar scattering angle
                tmp = app.plotdata.t_sun(yidx, xidx);
                if tmp > 60
                    app.Selected_SunTheta.FontColor = 'k';
                elseif tmp > 30
                    app.Selected_SunTheta.FontColor = [1 0.75 0] * 0.9;
                else
                    app.Selected_SunTheta.FontColor = 'r';
                end
                app.Selected_SunTheta.Text = num2str(tmp, '%05.1f\x00b0');
                
                % Data
                tmp = app.plotdata.h_image.CData(yidx, xidx);
                if isnan(tmp)
                    app.Selected_Dat.Text = 'NaN';
                else
                    if app.Stats_ToPlot.Value == 1
                        app.Selected_Dat.Text = num2str(tmp, '%.1f');
                    else
                        app.Selected_Dat.Text = num2str(tmp, '%.1f%%');
                    end
                end
            end
            
            % Ensure axis interactivity is off
            app.Ax_Results.Toolbar.Visible = 'off';
        end
        
        function sub_Plot_UpdateColorbar(app)
            % This subroutine updates the colorbar based on the existing
            % data
            
            % Get range
            lo = floor(10 * nanmin(app.plotdata.h_image.CData, [], 'all')) / 10;
            hi = ceil(10 * nanmax(app.plotdata.h_image.CData, [], 'all')) / 10;
            if hi < 50
                hi = ceil(10 * prctile(app.plotdata.h_image.CData, 99, 'all')) / 10;
            end
            if app.Stats_ToPlot.Value == 2
                lo = nanmax(15, lo);
                hi = nanmin(50, hi);
            elseif app.Stats_ToPlot.Value == 3
                lo = nanmax(29, lo);
                hi = nanmin(45, hi);
            elseif app.Stats_ToPlot.Value == 4
                lo = nanmax(24, lo);
                hi = nanmin(40, hi);
            elseif app.Stats_ToPlot.Value == 5
                lo = nanmax(34, lo);
                hi = nanmin(50, hi);
            end
            
            % Adjust axis color limits
            caxis(app.Ax_Results, [lo, lo + (hi - lo) * 256 / 246])
            
            % Adjust colorbar ticks
            opts = [0.1, 0.25, 0.5, 1, 2, 5, 10, 25, 50, 100, 250, 500];
            [~, idx] = min(abs((hi - lo) ./ opts - 6));
            dtick = opts(idx);
            app.Cb_Results.Limits = [lo lo + (hi - lo) * 256 / 246];
            app.Cb_Results.Ticks = unique([lo : dtick : hi, hi]);
            
            % Update contour slider and edit
            app.IntEdit_Cont.Limits = [lo hi];
            app.IntSlide_Cont.Limits = [lo hi];
            app.IntSlide_Cont.Value = median([lo hi app.IntSlide_Cont.Value]);
            Int_ValueChanged(app, struct('Value', app.IntSlide_Cont.Value, 'Source', app.IntSlide_Cont))
        end
        
        function sub_Plot_UpdateSun(app)
            % This subroutine updates the solar position plot based on the
            % existing data
            
            % Plot sun path
            if any(abs(diff(app.sunpos.DailyAzi)) > 180)
                idx = find(abs(diff(app.sunpos.DailyAzi)) > 180);
                app.plotdata.h_sunpath(1).XData = app.sunpos.DailyAzi(1 : idx);
                app.plotdata.h_sunpath(1).YData = 90 - app.sunpos.DailyZen(1 : idx);
                app.plotdata.h_sunpath(2).XData = app.sunpos.DailyAzi(idx + 1 : end);
                app.plotdata.h_sunpath(2).YData = 90 - app.sunpos.DailyZen(idx + 1 : end);
                app.plotdata.h_sunpath(1).Visible = app.PlotVis_Sun.Value;
                app.plotdata.h_sunpath(2).Visible = app.PlotVis_Sun.Value;
            else
                app.plotdata.h_sunpath(1).XData = app.sunpos.DailyAzi;
                app.plotdata.h_sunpath(1).YData = 90 - app.sunpos.DailyZen;
                app.plotdata.h_sunpath(1).Visible = app.PlotVis_Sun.Value;
                app.plotdata.h_sunpath(2).Visible = 'off';
            end
            
            % Plot current sun
            app.plotdata.h_sunpoint.XData = app.sunpos.CurrentAzi;
            app.plotdata.h_sunpoint.YData = 90 - app.sunpos.CurrentZen;
            app.plotdata.h_sunpoint.Visible = app.PlotVis_Sun.Value;
        end
        
        function sub_Plot_UpdateWind(app)
            % This subroutine updates the wind plotting
            
            % Get directions
            winds = mod(app.IntSlide_Wind.Value + 90 + [0, 180], 360);
            los = mod(winds - acosd(0.95), 360);
            his = mod(winds + acosd(0.95), 360);
            
            % Plot
            app.plotdata.h_windnom(1).XData = winds(1) * [1 1];
            app.plotdata.h_windnom(2).XData = winds(2) * [1 1];
            app.plotdata.h_winderr(1).XData = los(1) * [1 1];
            app.plotdata.h_winderr(2).XData = los(2) * [1 1];
            app.plotdata.h_winderr(3).XData = his(1) * [1 1];
            app.plotdata.h_winderr(4).XData = his(2) * [1 1];
            app.plotdata.h_windnom(1).Visible = app.PlotVis_Win.Value;
            app.plotdata.h_windnom(2).Visible = app.PlotVis_Win.Value;
            app.plotdata.h_winderr(1).Visible = app.PlotVis_Win.Value;
            app.plotdata.h_winderr(2).Visible = app.PlotVis_Win.Value;
            app.plotdata.h_winderr(3).Visible = app.PlotVis_Win.Value;
            app.plotdata.h_winderr(4).Visible = app.PlotVis_Win.Value;
        end
        
        function sub_Plot_UpdateContour(app)
            % This subroutine updates the contour data in the plot
            
            % Get contour data
            c = contourc(app.plotdata.a_cam, app.plotdata.b_cam, app.plotdata.h_image.CData, app.IntSlide_Cont.Value * [1 1]);
            
            % Parse contour data
            set(app.plotdata.h_cont, 'Visible', 'off', 'XData', [], 'YData', [])
            if isempty(c); return; end
            k = 0;
            while 1
                k = k + 1;
                if k > length(app.plotdata.h_cont); return; end
                num = c(2, 1);
                app.plotdata.h_cont(k).XData = c(1, 2 : 1 + num);
                app.plotdata.h_cont(k).YData = c(2, 2 : 1 + num);
                app.plotdata.h_cont(k).Visible = app.PlotVis_Con.Value;
                if 2 + num > size(c, 2); break; end
                c = c(:, 2 + num : end);
            end
        end
        
        function sub_Plot_UpdateCam(app)
            % This subrouting updates the image extent within the figure
            
            % Update point
            app.plotdata.h_optpoint.XData = app.Selected_Azi.Value;
            app.plotdata.h_optpoint.YData = app.Selected_Inc.Value;
            app.plotdata.h_optpoint.Visible = 1;
            
            % Update extent
            xext = app.plotdata.h_optpoint.XData + atand((((1 : app.Cam_Width.Value) - 0.5) - app.Cam_Width.Value / 2) * app.Cam_Pitch.Value / 1e3 / app.Cam_Focal.Value);
            yext = app.plotdata.h_optpoint.YData + atand((((1 : app.Cam_Height.Value) - 0.5) - app.Cam_Height.Value / 2) * app.Cam_Pitch.Value / 1e3 / app.Cam_Focal.Value);
            xdat = mod([xext, xext(end) * ones(1, app.Cam_Height.Value), fliplr(xext), xext(1) * ones(1, app.Cam_Height.Value)], 360);
            ydat = [yext(1) * ones(1, app.Cam_Width.Value), yext, yext(end) * ones(1, app.Cam_Width.Value), fliplr(yext)];
            app.plotdata.h_optbound.XData = xdat;
            app.plotdata.h_optbound.YData = ydat;
            app.plotdata.h_optbound.Visible = app.PlotVis_Cam.Value;
        end
        
        
        function [] = sub_MaxDataUpdate(app, value)
            app.ismax = value;
            
            % Create string edit
            stredit =  ' - Period Maximum';
            if app.ismax_allgroup
                stredit = [stredit, ' - All Sky Groups'];
            end
            
            % Remove text in colorbar label
            idx = strfind(app.Cb_Results.Label.String, ' - Period');
            if ~isempty(idx)
                app.Cb_Results.Label.String = app.Cb_Results.Label.String(1 : idx - 1);
            end
            
            if app.ismax
                % Update colorbar label
                app.Cb_Results.Label.String = [app.Cb_Results.Label.String, stredit];
                
                % Highlight data that exits period maximum
                app.IntEdit_Plume_Label.FontColor = 'r';
                app.Panel_Stats.ForegroundColor = 'r';
                app.Stats_ToPlot_Label.FontColor = 'r';
                app.Stats_ctr_Label.FontColor  = 'r';
                app.Stats_var_Label.FontColor  = 'r';
                
                % Disable time adjustment
                app.IntEdit_Time.Enable = 'off';
                app.IntSlide_Time.Enable = 'off';
                
            else
                % Revert data that exits period maximum
                app.IntEdit_Plume_Label.FontColor = 'k';
                app.Panel_Stats.ForegroundColor = 'k';
                app.Stats_ToPlot_Label.FontColor = 'k';
                app.Stats_ctr_Label.FontColor  = 'k';
                app.Stats_var_Label.FontColor  = 'k';
                
                % Disable time adjustment
                app.IntEdit_Time.Enable = 'on';
                app.IntSlide_Time.Enable = 'on';
                
            end
        end
    end
    
    methods (Access = public)
        
        function SunPos = sub_SolarPosition(app, LOC_timevec, location, varargin)
            % This subroutine computes the solar position using the NREL Solar Position
            % Algorithm (NREL/TP-560-34302).
            % Inputs:
            %   LOC_timevec: A six element vector as per MATLAB's "datevec" format
            %   location: A three element vector with elements:
            %       1) Decimal latitude (positive North)
            %       2) Decimal longitude (positive East)
            %       3) UTC offset, necessary to compute the Julian Day
            %   Name-value pairs:
            %       - 'Press': Annual average local pressure    [mbar]  (default = 1013.25)
            %       - 'Temp': Annual average local temperature  [degC]  (default = 25)
            %       - 'Elevation': Observer elevation           [m]     (default = 0)
            % Outputs:
            %   SunPos: Structure of solar position, containing:
            %       - 'Sunrise': Timevec of sunrise in local time (nearest minute)
            %       - 'Sunset': Timevec of sunset in local time (nearest minute)
            %       - 'DailyAzi': Vector of solar azimuth during the day    [degrees]
            %       - 'DailyZen': Vector of solar zenith during the day     [degrees]
            %       - 'CurrentAzi': Vector of current solar azimuth         [degrees]
            %       - 'CurrentZen': Vector of current solar zenith          [degrees]
            
            % =========================================================== %
            % Check and parse inputs
            
            % Error checking for static use
            if numel(LOC_timevec) ~= 6
                err.message = '''timevec'' input must be a six-element time vector';
                err.identifier = 'SetupSkyLOSA_support_SolarPosition:timevec:InputError';
                err.stack = dbstack;
                uiwait(msgbox(err.message, 'Error', 'error', 'Modal'))
                error(err)
            end
            
            % Error checking for static use
            if numel(location) ~= 3
                err.message = '''location'' input must be a three-element numeric vector';
                err.identifier = 'SetupSkyLOSA_support_SolarPosition:location:InputError';
                err.stack = dbstack;
                uiwait(msgbox(err.message, 'Error', 'error', 'Modal'))
                error(err)
            end
            
            % Parse other inputs
            p = inputParser;
            p.FunctionName = 'SetupSkyLOSA_support_SolarPosition';
            addParameter(p, 'Press', 1013.25)
            addParameter(p, 'Temp', 25)
            addParameter(p, 'Elevation', 0)
            parse(p, varargin{:})
            
            % Error check name-value pairs for static use
            if any(structfun(@(x) or(or(~isscalar(x), x < 0), ~isnumeric(x)), p.Results))
                err.message = 'name-value inputs must be non-negative scalars';
                err.identifier = 'SetupSkyLOSA_support_SolarPosition:namevalue:InputError';
                err.stack = dbstack;
                uiwait(msgbox(err.message, 'Error', 'error', 'Modal'))
                error(err)
            end
            
            % Get Coordinates
            GPSlat = location(1);
            GPSlong = location(2);
            UTCoffset = location(3);
            clear location
            
            % Get Local datenum
            tmp = datevec(datenum(linspace(floor(datenum(LOC_timevec)), floor(datenum(LOC_timevec)) + 1, 1441)) - UTCoffset / 24);
            year = tmp(:, 1);
            month = tmp(:, 2);
            day = tmp(:, 3);
            hour = tmp(:, 4);
            minute = tmp(:, 5);
            second = tmp(:, 6);
            UTC_datenum = datenum(year, month, day, hour, minute, second);
            clear tmp
            
            % Get other inputs
            Elevation = p.Results.Elevation;
            Temp = p.Results.Temp;
            Press = p.Results.Press;
            clear p
            
            % Load model constants
            load(fullfile(app.folder_base, 'data', 'SetupSkyLOSA_Support_SolarPositionConstants.mat'), 'const')
            
            % =========================================================== %
            % Compute position over the day
            
            % SECTION 3.1
            D = day + (hour * 3600 + minute * 60 + second) / 86400;
            A = floor(year / 100);
            B = 2 - A + floor(A / 4);
            JD = floor(365.25 * (year + 4716)) + floor(30.6001 * (month + 1)) + D + B - 1524.5;
            JDE = JD + const.DT / 86400;
            JC  = (JD - 2451545) / 36525;
            JCE = (JDE - 2451545) / 36525;
            JME = JCE / 10;
            clear A B D JDE
            
            % SECTION 3.2
            L = cell2mat(cellfun(@(x) sum(x(:, 1) .* cos(x(:, 2) + x(:, 3) .* JME'), 1)', const.L, 'UniformOutput', false));
            L = sum(L .* (JME .^ (0 : 5)), 2) / 1e8 * 180 / pi;
            L = sub_RangeConstrain4SunPos(app, L, 360);
            B = cell2mat(cellfun(@(x) sum(x(:, 1) .* cos(x(:, 2) + x(:, 3) .* JME'), 1)', const.B, 'UniformOutput', false));
            B = sum(B .* (JME .^ (0 : 1)), 2) / 1e8 * 180 / pi;
            B = sub_RangeConstrain4SunPos(app, B, 360);
            R = cell2mat(cellfun(@(x) sum(x(:, 1) .* cos(x(:, 2) + x(:, 3) .* JME'), 1)', const.R, 'UniformOutput', false));
            R = sum(R .* (JME .^ (0 : 4)), 2) / 1e8 * 180 / pi;
            R = sub_RangeConstrain4SunPos(app, R, 360);
            
            % SECTION 3.3
            T = sub_RangeConstrain4SunPos(app, L + 180, 360);
            B = -B;
            
            % SECTION 3.4
            X = (JCE .^ (0 : 3)) * const.X';
            DP = sum((const.DA(:, 1)' + const.DA(:, 2)' .* JCE) .* sind(X * const.Y'), 2) / 360e5;
            DE = sum((const.DA(:, 3)' + const.DA(:, 4)' .* JCE) .* cosd(X * const.Y'), 2) / 360e5;
            clear JCE X
            
            % SECTION 3.5
            E = ((JME / 10) .^ (0 : 10)) * const.E0 / 3600 + DE;
            clear const DE JME
            
            % SECTION 3.6
            DT = -20.4898 / 3600 ./ R;
            
            % SECTION 3.7
            L = T + DP + DT;
            clear DT T
            
            % SECTION 3.8
            V = sub_RangeConstrain4SunPos(app, 280.46061837 + 360.98564736629 * (JD - 2451545) + 0.000387933 * JC .^ 2 - JC.^3 / 38710000, 360) + DP .* cosd(E);
            clear JC JD
            
            % SECTION 3.9
            A = sub_RangeConstrain4SunPos(app, atan2d(sind(L) .* cosd(E) - tand(B) .* sind(E), cosd(L)), 360);
            
            % SECTION 3.10
            D = asind(sind(B) .* cosd(E) + cosd(B) .* sind(E) .* sind(L)) .* (-1) .^ (GPSlat < 0);
            clear B E L
            
            % SECTION 3.11
            H = sub_RangeConstrain4SunPos(app, V + GPSlong - A, 360);
            clear A V
            
            % SECTION 3.12
            xi = 8.794 / 3600 ./ R;
            u = atan(0.99664719 * tand(GPSlat));
            x = cos(u) + Elevation / 6378140 * cosd(GPSlat);
            y = 0.99664719 * sin(u) + Elevation / 6378140 * sind(GPSlat);
            DA = atan2d(-x * sind(xi) .* sind(H), cosd(D) - x * sind(xi) .* cosd(H));
            DP = atan2d((sind(D) - y * sind(xi)) .* cosd(DA), cosd(D) - x * sind(xi) .* cosd(H));
            clear D R uxi x y
            
            % SECTION 3.13
            Hp = H - DA;
            clear DA H
            
            % SECTION 3.14
            e0 = asind(sind(GPSlat) * sind(DP) + cosd(GPSlat) * cosd(DP) .* cosd(Hp));
            De = Press / 1010 * 283 / (273 + Temp) * 1.02 / 60 ./ tand(e0 + 10.3 ./ (e0 + 5.11));
            e = e0 + De;
            Zenith = 90 - e;
            clear e0 De e
            
            % SECTION 3.15
            G = atan2d(sind(Hp), cosd(Hp) .* sind(GPSlat) - tand(DP) .* cosd(GPSlat));
            Azimuth = sub_RangeConstrain4SunPos(app, G + 180, 360);
            clear DP G Hp
            
            % =========================================================== %
            % Estimate sunrise, sunset, and current (to nearest second)
            
            % Test for 24-hr sun conditions
            if all(Zenith >= 90) % Total darkness (Error)
                err.message = 'No sun-up hours at this location & date.';
                err.identifier = 'SetupSkyLOSA_sub_SolarPosition:SunUpHours';
                err.stack = dbstack;
                uiwait(msgbox(err.message, 'Error', 'error', 'Modal'))
                error(err)
                
            elseif all(Zenith < 90) % Total sun
                idx_rise = 1;
                idx_set = 1440;
                
                % Warn the user via message box
                warn.message = '24 hours of sun at this location & date.';
                warn.identifier = 'SetupSkyLOSA_sub_SolarPosition:SunUpHours';
                uiwait(msgbox(warn.message, 'Warning', 'warn', 'Modal'))
                
            else % Normal day
                idx_rise = find(Zenith < 90, 1, 'first');
                idx_set = find(Zenith < 90, 1, 'last');
            end
            
            % NaN out nighttime
            Azimuth(Zenith > 90) = nan;
            Zenith(Zenith > 90) = nan;
            
            % Get current timestep index
            [~, idx_curr] = min(abs(UTC_datenum - datenum(LOC_timevec) + UTCoffset / 24));
            
            % =========================================================== %
            % Create output
            
            % Output data
            SunPos.sunrise = idx_rise;
            SunPos.sunset = idx_set;
            SunPos.current = idx_curr;
            SunPos.DailyAzi = Azimuth;
            SunPos.DailyZen = Zenith;
            SunPos.CurrentAzi = Azimuth(idx_curr);
            SunPos.CurrentZen = Zenith(idx_curr);
        end
        
        function data = sub_RangeConstrain4SunPos(~, data, mx)
            % This sub-routine rescales data to a given range as required by the
            % solar position calculation procedure
            temp = data / mx;
            data(temp > 0) = mx * rem(temp(temp > 0), 1);
            data(temp < 0) = mx - mx * abs(rem(temp(temp < 0), 1));
        end
        
        function sub_IntValueChanged_FromSubClass(app, event)
            Int_ValueChanged(app, event)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Figure_Startup(app)
            % This function is used to finalize app startup
            warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
            
            % Warn of slow loading
            if isdeployed
                str = 'SetupSkyLOSA is booting.\n\tPlease be patient, booting may take a minute.\n\n';
                fprintf(str)
            end
            
            % Write version number
            app.vers = '1.0';
            
            % =========================================================== %
            % Initialize app status
            app.status.LoadedSky = 0;
            app.status.Interactable = 0;
            app.status.MaximizerExitAck = 0;
            app.ismax = 0;
            app.DialogCancelled = 0;
            
            % =========================================================== %
            % Position app in screen center and lock it (no resize... for now)
            % Get screen size
            sz = get(groot, 'ScreenSize');
            sw = sz(3);
            sh = sz(4);
            
            % Get current size of the app
            aw = app.Figure.Position(3);
            ah = app.Figure.Position(4);
            
            % Get update of the left position of the app
            l = (sw - aw) / 2; % Centered horizontally
            
            % Get update of the bottom position of the app
            b = sh - 40 - ah; % 40 px from top of screen
            
            % Update figure position and turn off resizing
            app.Figure.Position(1 : 2) = [l b];
            app.Figure.Resize = 'off';
            
            % =========================================================== %
            % Look for app support folder in default location
            if ~isdeployed
                app.folder_base = fullfile(fileparts(which(mfilename)), 'support');
            else
                app.folder_base = fullfile(ctfroot, 'SetupSkyLOSA', 'support');
            end
            
            % =========================================================== %
            % Load images
            app.Im_Carleton.ImageSource = fullfile(app.folder_base, 'images', 'SetupSkyLOSA_Images_Carleton.png');
            app.Im_EERL.ImageSource = fullfile(app.folder_base, 'images', 'SetupSkyLOSA_Images_EERL.png');
            app.Im_FlareNet.ImageSource = fullfile(app.folder_base, 'images', 'SetupSkyLOSA_Images_FlareNet.png');
            
            % =========================================================== %
            % Add tooltips
            % Get strings
            fID = fopen(fullfile(app.folder_base, 'tooltips.txt'), 'rt');
            str = textscan(fID, '%s', 'Delimiter', '\n', 'CollectOutput', true);
            str = str{1}(~cellfun('isempty', str{1}));
            for idx = 1 : length(str)
                tmp = strsplit(str{idx}, '\t');
                app.(tmp{1}).Tooltip =  split(tmp{2}, '\t');
            end
            fclose(fID);
            clear str
            
            % =========================================================== %
            % Update default date
            Time_ResetPushed(app, [])
            
            % =========================================================== %
            % Define plotting colours
            app.colors.sun = [1.0 0.3 0.0];
            app.colors.con = [0.0 0.7 0.0];
            app.colors.cam = [0.7 0.0 0.8];
            app.colors.win = [0.0 0.6 0.8];
            
            % Set plot visibility colours
            app.PlotVis_Sun.BackgroundColor = app.colors.sun;
            app.PlotVis_Con.BackgroundColor = app.colors.con;
            app.PlotVis_Cam.BackgroundColor = 0.94 * [1 1 1];
            app.PlotVis_Win.BackgroundColor = 0.94 * [1 1 1];
            app.PlotVis_Sun.FontColor = 0.94 * [1 1 1];
            app.PlotVis_Con.FontColor = 0.94 * [1 1 1];
            app.PlotVis_Cam.FontColor = app.colors.cam;
            app.PlotVis_Win.FontColor = app.colors.win;
            app.PlotVis_Cam.Value = 0;
            app.PlotVis_Win.Value = 0;
            
            % =========================================================== %
            % Set dropdown itemsdata and confirm current
            app.Stats_ToPlot.ItemsData = 1 : 5;
            app.Stats_ctr.ItemsData = 1 : 2;
            app.Stats_var.ItemsData = 1 : 3;
            app.Stats_ToPlot.Value = 2;
            app.Stats_ctr.Value = 1;
            app.Stats_var.Value = 2;
            
            % =========================================================== %
            % Create plotting axis
            app.Ax_Results = uiaxes(app.Figure, 'Units', 'pixels', 'Position', [-1000 -1000 600 600]);
            
            % Format plotting axis shape and limits
            app.Ax_Results.NextPlot = 'add';
            app.Ax_Results.Color = 'w';
            app.Ax_Results.PlotBoxAspectRatio = [4 1 1];
            app.Ax_Results.XLim = [0 360];
            app.Ax_Results.YLim = [0 90];
            app.Ax_Results.XTick = 0 : 45 : 360;
            app.Ax_Results.YTick = 0 : 15 : 90;
            app.Ax_Results.YDir = 'normal';
            app.Ax_Results.TickDir = 'out';
            app.Ax_Results.Box = 'on';
            
            % Update axis labelling
            app.Ax_Results.XLabel.String = ['Camera Bearing (CW from North), {\fontname{cambria}{\it\alpha_{sN}}} [', char(176), ']'];
            app.Ax_Results.YLabel.String = ['Camera Inclination, {\fontname{cambria}{\it\beta}} [', char(176), ']'];
            app.Ax_Results.XLabel.FontUnits = 'points';
            app.Ax_Results.YLabel.FontUnits = 'points';
            app.Ax_Results.XLabel.FontSize = 14;
            app.Ax_Results.YLabel.FontSize = 14;
            
            % Update tick labelling
            xt = {'0 (N)', '45', '90 (E)', '135', '180 (S)', '225', '270 (W)', '315', '360 (N)'};
            app.Ax_Results.XTickLabel =  xt;
            app.Ax_Results.FontUnits = 'points';
            app.Ax_Results.FontSize = 12;
            app.Ax_Results.Interactions = [];
            
            % Disable axis interactivity
            disableDefaultInteractivity(app.Ax_Results)
            
            % =========================================================== %
            % Load colormap
            cmap = importdata(fullfile(app.folder_base, 'colormaps', 'cividis-rgb.txt'));
            
            % Interpolate for sizing
            cmap = interpn(linspace(0, 1, size(cmap,1))', 1 : 3, cmap, linspace(0, 1, 246)', 1 : 3, 'linear');
            
            % Append range to white
            tmp = interpn([1; 10], 1 : 3, [cmap(end, :); 1 1 1], (1 : 10)', 1 : 3, 'linear');
            cmap = cat(1, cmap, tmp);
            
            % Set colormap of axis
            app.Ax_Results.Colormap = cmap;
            caxis(app.Ax_Results, [10 10 + 40 * 256 / 246])
            app.Ax_Results.CLimMode = 'manual';
            
            % =========================================================== %
            % Create results colorbar
            app.Cb_Results = colorbar(app.Ax_Results, 'NorthOutside');
            
            % Format colorbar
            app.Cb_Results.TickDirection = 'both';
            app.Cb_Results.LineWidth = 0.5;
            app.Cb_Results.FontSize = 10;
            
            % Set colorbar label
            app.Cb_Results.Label.String = 'Coefficient of Variation [%]';
            app.Cb_Results.Label.FontSize = 12;
            
            % =========================================================== %
            % Initialize plots
            % Image
            app.plotdata.a_cam = 0 : 0.5 : 360;
            app.plotdata.b_cam = (0 : 0.5 : 90)';
            app.plotdata.h_image = imagesc(app.Ax_Results, app.plotdata.a_cam, app.plotdata.b_cam, zeros(181,761) + nan, 'visible', 'off');
            app.Ax_Results.YDir = 'normal';
            % sub_Plot_UpdateColorbar(app)
            
            % Wind directions
            app.plotdata.h_windnom(1, 1) = plot(app.Ax_Results, [0 0], [0 90], '-k', 'color', app.colors.win, 'linewidth', 1, 'visible', 'off');
            app.plotdata.h_windnom(2, 1) = plot(app.Ax_Results, [0 0], [0 90], '-k', 'color', app.colors.win, 'linewidth', 1, 'visible', 'off');
            app.plotdata.h_winderr(1, 1) = plot(app.Ax_Results, [0 0], [0 90], '--k', 'color', app.colors.win, 'linewidth', 1, 'visible', 'off');
            app.plotdata.h_winderr(2, 1) = plot(app.Ax_Results, [0 0], [0 90], '--k', 'color', app.colors.win, 'linewidth', 1, 'visible', 'off');
            app.plotdata.h_winderr(3, 1) = plot(app.Ax_Results, [0 0], [0 90], '--k', 'color', app.colors.win, 'linewidth', 1, 'visible', 'off');
            app.plotdata.h_winderr(4, 1) = plot(app.Ax_Results, [0 0], [0 90], '--k', 'color', app.colors.win, 'linewidth', 1, 'visible', 'off');
            
            % Sun path
            app.plotdata.h_sunpath(1, 1) = plot(app.Ax_Results, 0, 0, '--k', 'color', app.colors.sun, 'linewidth', 1, 'visible', 'off');
            app.plotdata.h_sunpath(2, 1) = plot(app.Ax_Results, 0, 0, '--k', 'color', app.colors.sun, 'linewidth', 1, 'visible', 'off');
            
            % Sun point
            app.plotdata.h_sunpoint = plot(app.Ax_Results, 0, 0, 'ok', 'markersize', 10, 'markerfacecolor', app.colors.sun, 'markeredgecolor', 'none', 'visible', 'off');
            
            % Uncertainty Contours
            for idx = 1 : 25
                app.plotdata.h_cont(idx, 1) = plot(app.Ax_Results, 0, 0, '-r', 'color', app.colors.con, 'linewidth', 1, 'visible', 'off');
            end
            
            % Optics - point and plume
            app.plotdata.h_optpoint = plot(app.Ax_Results, nan, nan, 'r+', 'markersize', 6, 'markerfacecolor', app.colors.cam, 'markeredgecolor', app.colors.cam, 'linewidth', 2, 'visible', 'on');
            app.plotdata.h_optbound = plot(app.Ax_Results, nan, nan, 'rs', 'markersize', 2, 'markerfacecolor', app.colors.cam, 'markeredgecolor', 'none', 'visible', 'off');
            
            % Bring axis to front
            app.Ax_Results.Layer = 'top';
            
            % =========================================================== %
            % Set axis position in figure and make everything visible
            sub_UpdateAxisPosition(app);
            app.Cb_Results.Visible = 'on';
            
            % Assign app in base workspace for troubleshooting and
            % interaction
            assignin('base', 'app', app)
            
        end

        % Window button motion function: Figure
        function Figure_MouseMotion(app, event)
            % This callback obtains the current position of the pointer and,
            % if it over the axis, updates the "cursor position" data
            
            if app.status.Interactable
                % Get cursor position
                cp = app.Ax_Results.CurrentPoint;
                
                % Test for cursor over axis
                test(1) = and(cp(1, 1) >= 0, cp(1, 1) <= 360);
                test(2) = and(cp(1, 2) >= 0, cp(1, 2) <= 090);
                
                % Update impixelinfo
                if all(test)
                    % Get indices
                    xidx = round(2 * cp(1, 1) + 1);
                    yidx = round(2 * cp(1, 2) + 1);
                    
                    % Positin on axes
                    app.Current_Azi.Text = num2str(app.plotdata.a_cam(xidx), ['%05.1f', char(176)]);
                    app.Current_Inc.Text = num2str(app.plotdata.b_cam(yidx), ['%04.1f', char(176)]);
                    
                    % Solar scattering angle
                    tmp = app.plotdata.t_sun(yidx, xidx);
                    if tmp > 60
                        app.Current_SunTheta.FontColor = 'k';
                    elseif tmp > 30
                        app.Current_SunTheta.FontColor = [1 0.75 0] * 0.9;
                    else
                        app.Current_SunTheta.FontColor = 'r';
                    end
                    app.Current_SunTheta.Text = num2str(tmp, ['%05.1f', char(176)]);
                    
                    % Data point
                    tmp = app.plotdata.h_image.CData(yidx, xidx);
                    if isnan(tmp)
                        app.Current_Dat.Text = 'NaN';
                    else
                        if app.Stats_ToPlot.Value == 1
                            app.Current_Dat.Text = num2str(tmp, '%.1f');
                        else
                            app.Current_Dat.Text = num2str(tmp, '%.1f%%');
                        end
                    end
                else
                    app.Current_Azi.Text = '- - - - -';
                    app.Current_Inc.Text = '- - - - -';
                    app.Current_SunTheta.Text = '- - - - -';
                    app.Current_Dat.Text = '- - - - -';
                end
            end
        end

        % Window button down function: Figure
        function Figure_MouseClick(app, event)
            % This callback obtains the current position of the pointer and,
            % if it over the axis, updates the "selected position" data
            
            if app.status.Interactable
                % Get cursor position
                cp = app.Ax_Results.CurrentPoint;
                
                % Test for cursor over axis
                test(1) = and(cp(1, 1) >= 0, cp(1, 1) <= 360);
                test(2) = and(cp(1, 2) >= 0, cp(1, 2) <= 090);
                
                % Update impixelinfo
                if all(test)
                    % Get indices
                    xidx = round(2 * cp(1, 1) + 1);
                    yidx = round(2 * cp(1, 2) + 1);
                    
                    % Positin on axes
                    app.Selected_Azi.Value = app.plotdata.a_cam(xidx);
                    app.Selected_Inc.Value = app.plotdata.b_cam(yidx);
                    
                    % Solar scattering angle
                    tmp = app.plotdata.t_sun(yidx, xidx);
                    if tmp > 60
                        app.Selected_SunTheta.FontColor = 'k';
                    elseif tmp > 30
                        app.Selected_SunTheta.FontColor = [1 0.75 0] * 0.9;
                    else
                        app.Selected_SunTheta.FontColor = 'r';
                    end
                    app.Selected_SunTheta.Text = num2str(tmp, ['%05.1f', char(176)]);
                    
                    % Data point
                    tmp = app.plotdata.h_image.CData(yidx, xidx);
                    if isnan(tmp)
                        app.Selected_Dat.Text = 'NaN';
                    else
                        if app.Stats_ToPlot.Value == 1
                            app.Selected_Dat.Text = num2str(tmp, '%.1f');
                        else
                            app.Selected_Dat.Text = num2str(tmp, '%.1f%%');
                        end
                    end
                    
                    % Update plot
                    sub_Plot_UpdateCam(app)
                end
            end
        end

        % Callback function: Maximizer_Button, Menu_ShowPeriodMax
        function Menu_ComputePeriodMax_Fun(app, event)
            % Pause app
            app.status.Interactable = 0;
            h = findobj(allchild(app.Figure), '-property', 'enable');
            oldstatus = get(h, 'enable');
            set(h, 'enable', 'off')
            
            % Fun dialog application
            app.DialogApp = SetupSkyLOSA_ObtainMaximumData(app);
            uiwait(app.Figure)
            if and(~app.status.MaximizerExitAck, ~app.DialogCancelled)
                SetupSkyLOSA_MaximizerExitInfo(app)
            end
            app.DialogCancelled = 0;
            
            % Once complete, re-enable app
            arrayfun(@(idx) set(h(idx), 'enable', oldstatus{idx}), 1 : length(h))
            app.status.Interactable = 1;
            
            % Update status and colorbar
            sub_MaxDataUpdate(app, app.ismax)
            sub_Plot_UpdateColorbar(app)
        end

        % Callback function: Menu_ComputePermissibleLoc, 
        % Positioner_Button
        function Menu_ComputePermissibleLoc_Fun(app, event)
            % Pause app
            app.status.Interactable = 0;
            cam_parent = allchild(findobj(allchild(app.Figure), 'Type', 'uipanel', 'Title', 'Define Camera/Sensor:'));
            h = findobj(allchild(app.Figure), '-property', 'enable', '-not', 'parent', cam_parent);
            oldstatus = get(h, 'enable');
            set(h, 'enable', 'off')
            
            % Fun dialog application
            app.DialogApp = SetupSkyLOSA_PlotPermissibleLocation(app);
            uiwait(app.Figure)
            
            % Once complete, re-enable app
            arrayfun(@(idx) set(h(idx), 'enable', oldstatus{idx}), 1 : length(h))
            app.status.Interactable = 1;
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            % This callback closes the app
            
            % Close the app
            delete(app)
        end

        % Menu selected function: Menu_License
        function Menu_LicenseSelected(app, event)
            % This callback opens a window to show the license file
            
            % Get license file
            filename = fullfile(app.folder_base, 'SetupSkyLOSA-License_copy.txt');
            
            % Load license
            str = strsplit(fileread(filename), char([13, 10]))';
            str = {str{1}; ' '; str{2}; ' '; str{3}; ' '; str{4}};
            str{1} = strrep(str{1}, '(c)', '\copyright');
            
            % Clear old license window (if exists)
            try %#ok<TRYNC>
                close 'Software License'
            end
            
            % Display license in new window
            obj = figure('units', 'pixels', 'position', [200 200 600 500], 'NumberTitle', 'off', 'Name', 'Software License');
            set(obj, 'menubar', 'none', 'toolbar', 'none', 'resize', 'off', 'color', [0.94 0.94 0.94])
            ax = axes(obj, 'units', 'pixels', 'position', [0 0 600 500]);
            tmp = uicontrol(obj, 'style', 'text', 'units', 'normalized', 'position', [0.1 0 0.8 1], 'FontSize', 12);
            str = textwrap(tmp, str);
            delete(tmp)
            text(ax, 0.1, 0.5, str, 'FontSize', 11, 'HorizontalAlignment', 'left', 'verticalalignment', 'middle')
            set(ax, 'xlim', [0 1], 'ylim', [0 1], 'visible', 'off')
            
            % Center license over window
            % Center window in calling object
            obj.Position(1) = app.Figure.Position(1) + app.Figure.Position(3) / 2 - obj.Position(3) / 2;
            obj.Position(2) = app.Figure.Position(2) + app.Figure.Position(4) / 2 - obj.Position(4) / 2;
        end

        % Menu selected function: Menu_Colourmap
        function Menu_ColourmapSelected(app, event)
            % This callback opens a window to show the colourmap information
            
            % Get license file
            filename = fullfile(app.folder_base, 'colormaps', 'cividis-license.txt');
            
            % Load license
            str = strsplit(fileread(filename), char([13, 10]))';
            str = cellfun(@(x) strrep(x, '...', ''), str, 'uni', 0);
            str = cellfun(@(x) strrep(x, '(c)', '\copyright'), str, 'uni', 0);
            str = cellfun(@(x) strrep(x, 'Nunez', ['Nu', char(241), 'ez']), str, 'uni', 0);
            
            % Clear old window (if exists)
            try %#ok<TRYNC>
                close 'Colourmap Information'
            end
            
            % Display license in new window
            obj = figure('units', 'pixels', 'position', [200 200 1000 700], 'NumberTitle', 'off', 'Name', 'Colourmap Information');
            set(obj, 'menubar', 'none', 'toolbar', 'none', 'resize', 'off', 'color', [0.94 0.94 0.94])
            ax = axes(obj, 'units', 'pixels', 'position', [0 0 1000 700]);
            tmp = uicontrol(obj, 'style', 'text', 'units', 'normalized', 'position', [0.1 0 0.8 1], 'FontSize', 12);
            str = textwrap(tmp, str);
            delete(tmp)
            text(ax, 0.1, 0.5, str, 'FontSize', 11, 'HorizontalAlignment', 'left', 'verticalalignment', 'middle')
            set(ax, 'xlim', [0 1], 'ylim', [0 1], 'visible', 'off')
            
            % Center license over window
            % Center window in calling object
            obj.Position(1) = app.Figure.Position(1) + app.Figure.Position(3) / 2 - obj.Position(3) / 2;
            obj.Position(2) = app.Figure.Position(2) + app.Figure.Position(4) / 2 - obj.Position(4) / 2;
        end

        % Menu selected function: Menu_Docs
        function Menu_DocsSelected(app, event)
            % This callback opens the readthedocs documentation in the user's
            % browser
            
            % Detect OS and go to webpage
            if ispc
                system('start http://SetupSkyLOSA.readthedocs.io/');
            elseif ismac
                system('open http://SetupSkyLOSA.readthedocs.io/');
            end
        end

        % Menu selected function: Menu_Update
        function Menu_UpdateSelected(app, event)
            % This callback opens the app's GitHub page in the user's
            % browser, where the user can determine whether they are using
            % the latest version
            
            % Display message box with current version info
            msg = ['You are using SetupSkyLOSA version ', app.vers, '.'];
            uialert(app.Figure, msg, '', 'icon', 'info', 'CloseFcn', 'uiresume(app.Figure)')
            uiwait(app.Figure)
            
            % Detect OS and go to webpage
            if ispc
                system('start https://github.com/bradleymconrad/SetupSkyLOSA#version-history');
            elseif ismac
                system('open https://github.com/bradleymconrad/SetupSkyLOSA#version-history');
            end
            
        end

        % Value changed function: Time_Source
        function Time_SourceChanged(app, event)
            % This callback updates enabled components in the Time grid
            
            % Detect change
            if app.Time_Source.Value % Checked
                app.Time_Date.Enable = 'off';
                app.Time_Ho.Enable = 'off';
                app.Time_Mi.Enable = 'off';
                app.Time_UTC.Enable = 'off';
                app.Time_Reset.Enable = 'on';
                app.Time_Date_Label.Enable = 'off';
                app.Time_Ho_Label.Enable = 'off';
                app.Time_Mi_Label.Enable = 'off';
                app.Time_UTC_Label.Enable = 'off';
                
            else % Unchecked
                app.Time_Date.Enable = 'on';
                app.Time_Ho.Enable = 'on';
                app.Time_Mi.Enable = 'on';
                app.Time_UTC.Enable = 'on';
                app.Time_Reset.Enable = 'off';
                app.Time_Date_Label.Enable = 'on';
                app.Time_Ho_Label.Enable = 'on';
                app.Time_Mi_Label.Enable = 'on';
                app.Time_UTC_Label.Enable = 'on';
            end
        end

        % Button pushed function: Time_Reset
        function Time_ResetPushed(app, event)
            % This callback updates the time data to the CPU's current time
            
            % Get current time and UTC offset
            tmp = floor(clock);
            utc = int8((datenum(datetime('now')) - datenum(datetime('now', 'TimeZone', 'Etc/GMT'))) * 24);
            
            % Update inputs
            app.Time_Date.Value = datetime('today');
            app.Time_Ho.Value = tmp(4);
            app.Time_Mi.Value = tmp(5);
            app.Time_UTC.Value = double(utc);
        end

        % Button pushed function: Loc_OtherCompute
        function Loc_OtherButtonPushed(app, event)
            % This callback opens a dialog allowing the user to input GPS
            % coordinates inalternative formats
            
            % Pause app
            h = findobj(allchild(app.Figure), '-property', 'enable', '-not', 'type', 'uistatebutton');
            oldstatus = get(h, 'enable');
            set(h, 'enable', 'off')
            
            % Initialize dialog application
            app.DialogApp = SetupSkyLOSA_AlternativeGPSInput(app);
            uiwait(app.Figure)
            
            % Once complete, re-enable app
            arrayfun(@(idx) set(h(idx), 'enable', oldstatus{idx}), 1 : length(h))
        end

        % Value changed function: Cam_Source
        function Cam_SourceChanged(app, event)
            % This callback enables/disables optics settings based on the
            % value, and updates numerical data as required
            
            % Get the current value and update enabling/disabling and
            % values, as required
            h = [app.Cam_Pitch, app.Cam_Pitch_Label, app.Cam_Width, ...
                app.Cam_Width_Label, app.Cam_Height, app.Cam_Height_Label];
            if strcmpi(event.Value, 'Manual')
                set(h, 'enable', app.PlotVis_Cam.Value)
                
            else
                set(h, 'enable', 'off')
                
                % Update values
                switch event.Value
                    case 'CIS2521'
                        app.Cam_Pitch.Value = 6.5;
                        app.Cam_Width.Value = 2560;
                        app.Cam_Height.Value = 2160;
                        
                    case 'CIS2521mod'
                        app.Cam_Pitch.Value = 6.5;
                        app.Cam_Width.Value = 2048;
                        app.Cam_Height.Value = 1536;
                        
                    case 'GMAX0505'
                        app.Cam_Pitch.Value = 2.5;
                        app.Cam_Width.Value = 5120;
                        app.Cam_Height.Value = 5120;
                end
            end
            
            % Run value changed function
            Cam_ValueChanged(app, event)
        end

        % Value changed function: Cam_Focal, Cam_Height, Cam_Pitch, 
        % Cam_Width
        function Cam_ValueChanged(app, event)
            % This callback updates app data upon changing of camera
            % optics, it also updates the figure if it is interactable
            
            % Update image extent if interactable
            if app.status.Interactable
                sub_Plot_UpdateCam(app)
            end
        end

        % Value changed function: Stats_ToPlot, Stats_ctr, Stats_var
        function Stats_ValueChanged(app, event)
            % This callback updates the plotted image data
            sub_MaxDataUpdate(app, 0)
            
            % Update colorbar label, string cursor position labelling, and
            % slider format if "ToPlot" is changed
            if event.Source == app.Stats_ToPlot
                app.Stats_var.Enable = ~~(app.Stats_ToPlot.Value == 2);
                app.Stats_var_Label.Enable = ~~(app.Stats_ToPlot.Value == 2);
                app.Menu_ShowPeriodMax.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
                app.Menu_ComputePermissibleLoc.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
                app.Maximizer_Button.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
                app.Positioner_Button.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
                
                % Update labelling
                switch event.Value
                    case 1 % Central tendency - column density
                        app.Cb_Results.Label.String = 'Column Density [mg m^{-2}]';
                        app.Cursor_Dat_Label.Text = 'Column Density [mg / m2]:';
                        app.IntEdit_Cont.ValueDisplayFormat = '%04.1f';
                    case 2 % Relative uncertainty - CV
                        app.Cb_Results.Label.String = 'Coefficient of Variation [%]';
                        app.Cursor_Dat_Label.Text = 'Relative Uncertainty:';
                        app.IntEdit_Cont.ValueDisplayFormat = '%04.1f%%';
                    case 3 % 95% confidence interval (RMS)
                        app.Cb_Results.Label.String = 'Tail-averaged 95% Confidence Interval [%]';
                        app.Cursor_Dat_Label.Text = 'Relative Uncertainty:';
                        app.IntEdit_Cont.ValueDisplayFormat = '%04.1f%%';
                    case 4 % 95% confidence interval (lower tail)
                        app.Cb_Results.Label.String = 'Lower 95% Confidence Interval [%]';
                        app.Cursor_Dat_Label.Text = 'Relative Uncertainty:';
                        app.IntEdit_Cont.ValueDisplayFormat = '%04.1f%%';
                    case 5 % 95% confidence interval (upper tail)
                        app.Cb_Results.Label.String = 'Upper 95% Confidence Interval [%]';
                        app.Cursor_Dat_Label.Text = 'Relative Uncertainty:';
                        app.IntEdit_Cont.ValueDisplayFormat = '%04.1f%%';
                end
                
                % Update selectable options for variation
                switch event.Value
                    case 2 % Coefficient of variation
                        app.Stats_var.Enable = 'on';
                    case {3, 4, 5} % Relative confidence interval
                        app.Stats_var.Value = 2;
                        app.Stats_var.Enable = 'off';
                end
            end
            
            % Update image if plotted
            if app.status.Interactable
                sub_Plot_UpdateImage(app)
                sub_Plot_UpdateContour(app)
                sub_Plot_UpdateColorbar(app)
                sub_Plot_UpdateCam(app)
            end
        end

        % Button pushed function: Fun_Run
        function Run_Pushed(app, event)
            % This callback runs the analysis on the button push
            
            % Start progress dialog
            hdlg = uiprogressdlg(app.Figure, 'icon', 'info', 'Indeterminate', 1);
            set(hdlg, 'Title', 'Loading Data', 'Message', 'Loading...')
            
            % Update time if using CPU time
            if app.Time_Source.Value
                Time_ResetPushed(app, [])
            end
            
            % Load stats data - if not already loaded
            if ~strcmp(app.Sky_Source.Value, app.status.LoadedSky)
                loadfile = fullfile(app.folder_base, 'data', ['SkyType_', app.Sky_Source.Value, '.mat']);
                tmp = load(loadfile, 'stats');
                app.sky.data = tmp.stats;
                app.status.LoadedSky = app.Sky_Source.Value;
                
                % Define sky data vectors
                app.sky.bv = app.sky.data.ang.bv;
                app.sky.av = app.sky.data.ang.av;
                app.sky.zv = app.sky.data.ang.zv;
                app.sky.tv = app.sky.data.T * 100;
                
                % Update tau range
                lo = min(app.sky.tv(:));
                hi = max(app.sky.tv(:));
                app.IntEdit_Plume.Limits = [lo hi];
                app.IntSlide_Plume.Limits = [lo hi];
            end
            
            % Update axis status
            app.status.Interactable = 1;
            
            % Compute Sun Position and update text
            timevec = datevec(app.Time_Date.Value) + [0 0 0 app.Time_Ho.Value app.Time_Mi.Value 0];
            lat = app.Loc_Lat.Value * (-1) ^ (app.Loc_LatHemi.Value - 1);
            lon = app.Loc_Lon.Value * (-1) ^ (app.Loc_LonHemi.Value - 1);
            locate = [lat lon app.Time_UTC.Value];
            app.sunpos = sub_SolarPosition(app, timevec, locate);
            
            % Warning test for sun above horizon
            if median([app.sunpos.sunrise app.sunpos.current app.sunpos.sunset]) ~= app.sunpos.current
                app.status.Interactable = 0;
                msg = {'Selected time is not during sun-up hours.'; ''; ...
                    'Refer to "Solar Position" panel for approximate sunrise and sunset.'; ''; ...
                    'Plotting data at local sunrise.'};
                uialert(app.Figure, msg, '', 'icon', 'warn', 'CloseFcn', 'uiresume(app.Figure)')
                uiwait(app.Figure)
                app.status.Interactable = 1;
                
                % Reset current data
                app.sunpos.CurrentAzi = app.sunpos.DailyAzi(app.sunpos.sunrise);
                app.sunpos.CurrentZen = app.sunpos.DailyZen(app.sunpos.sunrise);
                app.sunpos.current = app.sunpos.sunrise;
            end
            
            % Update sunrise and set data
            app.Sun_Rise.Text = strip(datestr(app.sunpos.sunrise / 1441, 15));
            app.Sun_Set.Text = strip(datestr(app.sunpos.sunset / 1441, 15));
            
            % Update current solar position data
            app.Sun_Azi.Text = num2str(app.sunpos.CurrentAzi, '%.1f\x00b0');
            app.Sun_Zen.Text = num2str(app.sunpos.CurrentZen, '%.1f\x00b0');
            
            % Update time adjustment interaction
            app.IntSlide_Time.Limits = [app.sunpos.sunrise app.sunpos.sunset] / 1441;
            app.IntSlide_Time.Value = app.sunpos.current / 1441;
            Int_ValueChanged(app, struct('Value', app.IntSlide_Time.Value, 'Source', app.IntSlide_Time))
            
            % Update cursor selection data point
            xidx = app.Selected_Azi.Value == app.plotdata.h_image.XData;
            yidx = app.Selected_Inc.Value == app.plotdata.h_image.YData;
            tmp = app.plotdata.h_image.CData(yidx, xidx);
            if isnan(tmp)
                app.Selected_Dat.Text = 'NaN';
            else
                if app.Stats_ToPlot.Value == 1
                    app.Selected_Dat.Text = num2str(tmp, '%.1f');
                else
                    app.Selected_Dat.Text = num2str(tmp, '%.1f%%');
                end
            end
            
            % Get data at current zenith and update default contour
            sub_GetStatsAtSolarZenith(app)
            
            % Update figure
            sub_Plot_UpdateImage(app)
            sub_Plot_UpdateSun(app)
            sub_Plot_UpdateWind(app)
            sub_Plot_UpdateColorbar(app)
            sub_Plot_UpdateCam(app)
            
            % Update uncertainty slider and uncertainty plot
            app.IntSlide_Cont.Value = mean(app.Ax_Results.CLim);
            Int_ValueChanged(app, struct('Value', app.IntSlide_Cont.Value, 'Source', app.IntSlide_Cont))
            sub_Plot_UpdateContour(app)
            
            % Disable/enable inputs
            flds = fields(app);
            cellfun(@(x) set(app.(x), 'Enable', 'off'), flds(startsWith(flds, 'Time_')))
            cellfun(@(x) set(app.(x), 'Enable', 'off'), flds(startsWith(flds, 'Loc_')))
            app.Sky_Source.Enable = 'off';
            app.Fun_Clear.Enable = 'on';
            cellfun(@(x) set(app.(x), 'Enable', 'on'), flds(startsWith(flds, 'IntEdit_')))
            cellfun(@(x) set(app.(x), 'Enable', 'on'), flds(startsWith(flds, 'IntSlide_')))
            cellfun(@(x) set(app.(x), 'Enable', 'on'), flds(startsWith(flds, 'PlotVis_')))
            app.Fun_Run.Enable = 'off';
            app.Selected_Azi.Enable = 'on';
            app.Selected_Inc.Enable = 'on';
            app.Menu_ShowPeriodMax.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
            app.Menu_ComputePermissibleLoc.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
            app.Maximizer_Button.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
            app.Positioner_Button.Enable = ~~(app.Stats_ToPlot.Value ~= 1);
            
            % Close dialog
            close(hdlg)
        end

        % Button pushed function: Fun_Clear
        function Clear_Pushed(app, event)
            % This callback clears the data and resets the axes
            
            % Make figure uninteractable
            app.status.Interactable = 0;
            sub_MaxDataUpdate(app, 0)
            
            % Update and enable for time data
            Time_SourceChanged(app, struct('Value', app.Time_Source.Value, 'Source', app.Time_Source))
            app.Time_Source.Enable = 'on';
            
            % Update and enable for GPS data
            app.Loc_Lat_Label.Enable = 'on';
            app.Loc_Lon_Label.Enable = 'on';
            app.Loc_LatHemi.Enable = 'on';
            app.Loc_LonHemi.Enable = 'on';
            
            % Enable sky data selection
            app.Sky_Source.Enable = 'on';
            
            % Update image data and colorbar
            app.plotdata.cdata = app.plotdata.cdata + nan;
            
            % Update image extent
            set(app.plotdata.h_optpoint, 'XData', NaN, 'YData', NaN)
            set(app.plotdata.h_optbound, 'XData', NaN, 'YData', NaN)
            
            % Reset time of day slider
            app.IntSlide_Time.Limits = [0 1];
            app.IntSlide_Time.Value = (app.Time_Ho.Value * 60 + app.Time_Mi.Value + 1) / 1441;
            Int_ValueChanged(app, struct('Value', app.IntSlide_Time.Value, 'Source', app.IntSlide_Time))
            
            % Disable sliders
            % Disable/enable inputs
            flds = fields(app);
            cellfun(@(x) set(app.(x), 'Enable', 'off'), flds(startsWith(flds, 'IntEdit_')))
            cellfun(@(x) set(app.(x), 'Enable', 'off'), flds(startsWith(flds, 'IntSlide_')))
            cellfun(@(x) set(app.(x), 'Enable', 'off'), flds(startsWith(flds, 'PlotVis_')))
            
            % Clear solar position data
            app.Sun_Rise.Text = '- - - - -';
            app.Sun_Set.Text = '- - - - -';
            app.Sun_Azi.Text = '- - - - -';
            app.Sun_Zen.Text = '- - - - -';
            
            % Clear selected point and update text color
            app.Current_SunTheta.FontColor = 'k';
            app.Selected_SunTheta.FontColor = 'k';
            
            % Clear axes (make all not visible)
            flds = fields(app.plotdata);
            cellfun(@(x) set(app.plotdata.(x), 'Visible', 'off'), flds(startsWith(flds, 'h_')))
            
            % Disable the button once pushed
            flds = fields(app);
            cellfun(@(x) set(app.(x), 'Enable', 'on'), flds(startsWith(flds, 'Loc_')))
            event.Source.Enable = 'off';
            app.Fun_Run.Enable = 'on';
            app.Selected_Azi.Enable = 'off';
            app.Selected_Inc.Enable = 'off';
            app.Menu_ShowPeriodMax.Enable = 'off';
            app.Menu_ComputePermissibleLoc.Enable = 'off';
            app.Maximizer_Button.Enable = 'off';
            app.Positioner_Button.Enable = 'off';
        end

        % Value changed function: PlotVis_Cam, PlotVis_Con, 
        % PlotVis_Sun, PlotVis_Win
        function PlotVis_ValueChanged(app, event)
            % This callback changes the visibility of plotted data when the
            % checkboxes are applied
            
            switch event.Source
                case app.PlotVis_Sun
                    if any(diff(app.sunpos.DailyAzi) > 180)
                        app.plotdata.h_sunpath(1).Visible = event.Value;
                        app.plotdata.h_sunpath(2).Visible = event.Value;
                    else
                        app.plotdata.h_sunpath(1).Visible = event.Value;
                        app.plotdata.h_sunpath(2).Visible = 'off';
                    end
                    app.plotdata.h_sunpoint.Visible = event.Value * ~app.ismax;
                    
                case app.PlotVis_Con
                    set(app.plotdata.h_cont, 'Visible', event.Value)
                    
                case app.PlotVis_Cam
                    app.plotdata.h_optbound.Visible = event.Value;
                    h = [app.Cam_Source, app.Cam_Source_Label, app.Cam_Pitch, app.Cam_Pitch_Label, ...
                        app.Cam_Width, app.Cam_Width_Label, app.Cam_Focal, app.Cam_Focal_Label, ...
                        app.Cam_Height, app.Cam_Height_Label];
                    set(h, 'enable', event.Value)
                    Cam_SourceChanged(app, struct('Value', app.Cam_Source.Value))
                    
                case app.PlotVis_Win
                    set(app.plotdata.h_winderr, 'Visible', event.Value)
                    set(app.plotdata.h_windnom, 'Visible', event.Value)
            end
            
            % Swap button visibility
            old_text = event.Source.FontColor;
            old_back = event.Source.BackgroundColor;
            event.Source.FontColor = old_back;
            event.Source.BackgroundColor = old_text;
        end

        % Image clicked function: Im_Carleton, Im_EERL, Im_FlareNet
        function Im_GeneralClicked(app, event)
            % This callback takes the user to the appropriate page in the user's browser
            
            % Get webpage string depending on event
            if contains(event.Source.ImageSource, 'Carleton')
                URL = 'https://carleton.ca/engineering-design';
            elseif contains(event.Source.ImageSource, 'EERL')
                URL = 'https://carleton.ca/eerl';
            elseif contains(event.Source.ImageSource, 'FlareNet')
                URL = 'https://flarenet.ca';
            end
            
            % Detect OS and go to webpage
            if ispc
                system(['start ', URL]);
            elseif ismac
                system(['open ', URL]);
            end
        end

        % Value changed function: IntEdit_Cont, IntEdit_Plume, 
        % IntEdit_Time, IntEdit_Wind, IntSlide_Cont, 
        % IntSlide_Plume, IntSlide_Time, IntSlide_Wind
        function Int_ValueChanged(app, event)
            % This callback links the slider and edit boxes in the plot
            % interactivity panel
            
            % Find the edited object and update it's partner
            switch event.Source
                case {app.IntEdit_Plume, app.IntSlide_Plume}
                    sub_MaxDataUpdate(app, 0)
                    app.IntEdit_Plume.Value = round(event.Value, 0, 'decimals');
                    app.IntSlide_Plume.Value = round(event.Value, 0, 'decimals');
                case {app.IntEdit_Wind, app.IntSlide_Wind}
                    app.IntEdit_Wind.Value = round(event.Value, 0, 'decimals');
                    app.IntSlide_Wind.Value = round(event.Value, 0, 'decimals');
                case {app.IntEdit_Cont, app.IntSlide_Cont}
                    app.IntEdit_Cont.Value = round(event.Value, 1, 'decimals');
                    app.IntSlide_Cont.Value = round(event.Value, 1, 'decimals');
                case app.IntEdit_Time
                    % Error check time input and update accordingly
                    try datenum(event.Value, 'HH:MM')
                        app.IntSlide_Time.Value = mod(datenum(event.Value), 1);
                    catch % Revert to previous time
                        app.IntEdit_Time.Value = event.PreviousValue;
                    end
                case app.IntSlide_Time
                    app.IntEdit_Time.Value = datestr(event.Value,15);
            end
            
            % Update results if interactable
            test = isempty(app.DialogApp);
            if ~test
                test = isvalid(app.DialogApp);
            end
            if or(app.status.Interactable, test)
                switch event.Source
                    case {app.IntEdit_Plume, app.IntSlide_Plume}
                        sub_Plot_UpdateImage(app)
                    case {app.IntEdit_Wind, app.IntSlide_Wind}
                        sub_Plot_UpdateWind(app)
                    case {app.IntEdit_Cont, app.IntSlide_Cont}
                        sub_Plot_UpdateContour(app)
                    case {app.IntEdit_Time, app.IntSlide_Time}
                        % Update current sun position
                        app.sunpos.CurrentAzi = app.sunpos.DailyAzi(round(app.IntSlide_Time.Value * 1440) + 1);
                        app.sunpos.CurrentZen = app.sunpos.DailyZen(round(app.IntSlide_Time.Value * 1440) + 1);
                        app.Sun_Azi.Text = num2str(app.sunpos.CurrentAzi, '%.1f\x00b0');
                        app.Sun_Zen.Text = num2str(app.sunpos.CurrentZen, '%.1f\x00b0');
  
                        sub_GetStatsAtSolarZenith(app)
                        sub_Plot_UpdateImage(app)
                        sub_Plot_UpdateSun(app)
                        
                end
            end
        end

        % Value changed function: Selected_Azi, Selected_Inc
        function SelectedPointing_ValueChanged(app, event)
            % This callback obtains the current position of the pointer and updates the "selected position" data
            
            % Update cursor selection data point
            xidx = app.Selected_Azi.Value == app.plotdata.h_image.XData;
            yidx = app.Selected_Inc.Value == app.plotdata.h_image.YData;
            tmp = app.plotdata.h_image.CData(yidx, xidx);
            if isnan(tmp)
                app.Selected_Dat.Text = 'NaN';
            else
                if app.Stats_ToPlot.Value == 1
                    app.Selected_Dat.Text = num2str(tmp, '%.1f');
                else
                    app.Selected_Dat.Text = num2str(tmp, '%.1f%%');
                end
            end
            
            % Solar scattering angle
            tmp = app.plotdata.t_sun(yidx, xidx);
            if tmp > 60
                app.Selected_SunTheta.FontColor = 'k';
            elseif tmp > 30
                app.Selected_SunTheta.FontColor = [1 0.75 0] * 0.9;
            else
                app.Selected_SunTheta.FontColor = 'r';
            end
            app.Selected_SunTheta.Text = num2str(tmp, ['%05.1f', char(176)]);
            
            % Data point
            tmp = app.plotdata.h_image.CData(yidx, xidx);
            if isnan(tmp)
                app.Selected_Dat.Text = 'NaN';
            else
                if app.Stats_ToPlot.Value == 1
                    app.Selected_Dat.Text = num2str(tmp, '%.1f');
                else
                    app.Selected_Dat.Text = num2str(tmp, '%.1f%%');
                end
            end
            
            % Update plot
            sub_Plot_UpdateCam(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'SetupSkyLOSA - a tool to support sky-LOSA data acquisition';
            app.Figure.WindowButtonDownFcn = createCallbackFcn(app, @Figure_MouseClick, true);
            app.Figure.WindowButtonMotionFcn = createCallbackFcn(app, @Figure_MouseMotion, true);
            app.Menu_File = uimenu(app.Figure);
            app.Menu_File.Text = '&File';
            app.Menu_ShowPeriodMax = uimenu(app.Menu_File);
            app.Menu_ShowPeriodMax.MenuSelectedFcn = createCallbackFcn(app, @Menu_ComputePeriodMax_Fun, true);
            app.Menu_ShowPeriodMax.Enable = 'off';
            app.Menu_ShowPeriodMax.Accelerator = 'm';
            app.Menu_ShowPeriodMax.Text = '"Maximizer": Compute Period Maximum';
            app.Menu_ComputePermissibleLoc = uimenu(app.Menu_File);
            app.Menu_ComputePermissibleLoc.MenuSelectedFcn = createCallbackFcn(app, @Menu_ComputePermissibleLoc_Fun, true);
            app.Menu_ComputePermissibleLoc.Enable = 'off';
            app.Menu_ComputePermissibleLoc.Accelerator = 'p';
            app.Menu_ComputePermissibleLoc.Text = '"Positioner": Compute Permissible Camera Locations';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Separator = 'on';
            app.Menu_Quit.Accelerator = 'W';
            app.Menu_Quit.Text = '&Close app';
            app.Menu_Help = uimenu(app.Figure);
            app.Menu_Help.Text = '&Info';
            app.Menu_License = uimenu(app.Menu_Help);
            app.Menu_License.MenuSelectedFcn = createCallbackFcn(app, @Menu_LicenseSelected, true);
            app.Menu_License.Text = 'Software &license';
            app.Menu_Colourmap = uimenu(app.Menu_Help);
            app.Menu_Colourmap.MenuSelectedFcn = createCallbackFcn(app, @Menu_ColourmapSelected, true);
            app.Menu_Colourmap.Text = 'Colourmap';
            app.Menu_Docs = uimenu(app.Menu_Help);
            app.Menu_Docs.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocsSelected, true);
            app.Menu_Docs.Text = 'Go to &documentation';
            app.Menu_Update = uimenu(app.Menu_Help);
            app.Menu_Update.MenuSelectedFcn = createCallbackFcn(app, @Menu_UpdateSelected, true);
            app.Menu_Update.Text = 'Check for &updates';
            app.Grid_Main = uigridlayout(app.Figure);
            app.Grid_Inputs = uigridlayout(app.Grid_Main);
            app.Panel_Time = uipanel(app.Grid_Inputs);
            app.Panel_Time.Title = 'Set Date and Time:';
            app.Grid_Time = uigridlayout(app.Panel_Time);
            app.Time_Date_Label = uilabel(app.Grid_Time);
            app.Time_Date_Label.Enable = 'off';
            app.Time_Date_Label.Text = 'Select Date:';
            app.Time_Date = uidatepicker(app.Grid_Time);
            app.Time_Date.Enable = 'off';
            app.Time_Ho_Label = uilabel(app.Grid_Time);
            app.Time_Ho_Label.Enable = 'off';
            app.Time_Ho_Label.Text = 'Hr. (hh):';
            app.Time_Ho = uispinner(app.Grid_Time);
            app.Time_Ho.UpperLimitInclusive = 'off';
            app.Time_Ho.Limits = [0 24];
            app.Time_Ho.ValueDisplayFormat = '%02u';
            app.Time_Ho.Enable = 'off';
            app.Time_Mi_Label = uilabel(app.Grid_Time);
            app.Time_Mi_Label.Enable = 'off';
            app.Time_Mi_Label.Text = 'Min. (mm):';
            app.Time_Mi = uispinner(app.Grid_Time);
            app.Time_Mi.UpperLimitInclusive = 'off';
            app.Time_Mi.Limits = [0 60];
            app.Time_Mi.RoundFractionalValues = 'on';
            app.Time_Mi.ValueDisplayFormat = '%02u';
            app.Time_Mi.Enable = 'off';
            app.Time_UTC_Label = uilabel(app.Grid_Time);
            app.Time_UTC_Label.Enable = 'off';
            app.Time_UTC_Label.Text = 'UTC Offset:';
            app.Time_UTC = uispinner(app.Grid_Time);
            app.Time_UTC.Step = 0.5;
            app.Time_UTC.Limits = [-11 13];
            app.Time_UTC.ValueDisplayFormat = '%+.1f';
            app.Time_UTC.Enable = 'off';
            app.Time_UTC.Value = -4;
            app.Time_Source = uicheckbox(app.Grid_Time);
            app.Time_Source.ValueChangedFcn = createCallbackFcn(app, @Time_SourceChanged, true);
            app.Time_Source.Text = 'Use CPU Time';
            app.Time_Source.Value = true;
            app.Time_Reset = uibutton(app.Grid_Time, 'push');
            app.Time_Reset.ButtonPushedFcn = createCallbackFcn(app, @Time_ResetPushed, true);
            app.Time_Reset.Text = 'Reset Local Time';
            app.Panel_Loc = uipanel(app.Grid_Inputs);
            app.Panel_Loc.Title = 'Set Measurement Location:';
            app.Grid_Loc = uigridlayout(app.Panel_Loc);
            app.Loc_Lat_Label = uilabel(app.Grid_Loc);
            app.Loc_Lat_Label.Text = 'Latitude:';
            app.Loc_Lat = uispinner(app.Grid_Loc);
            app.Loc_Lat.Step = 0.01;
            app.Loc_Lat.UpperLimitInclusive = 'off';
            app.Loc_Lat.Limits = [0 90];
            app.Loc_Lat.ValueDisplayFormat = '%.6f\x00b0';
            app.Loc_Lat.Value = 45.384272;
            app.Loc_LatHemi = uidropdown(app.Grid_Loc);
            app.Loc_LatHemi.Items = {'North', 'South'};
            app.Loc_LatHemi.ItemsData = {'1', '2'};
            app.Loc_LatHemi.Value = '1';
            app.Loc_Lon_Label = uilabel(app.Grid_Loc);
            app.Loc_Lon_Label.Text = 'Longitude:';
            app.Loc_Lon = uispinner(app.Grid_Loc);
            app.Loc_Lon.Step = 0.01;
            app.Loc_Lon.UpperLimitInclusive = 'off';
            app.Loc_Lon.Limits = [0 180];
            app.Loc_Lon.ValueDisplayFormat = '%.6f\x00b0';
            app.Loc_Lon.Value = 75.698541;
            app.Loc_LonHemi = uidropdown(app.Grid_Loc);
            app.Loc_LonHemi.Items = {'East', 'West'};
            app.Loc_LonHemi.ItemsData = {'1', '2'};
            app.Loc_LonHemi.Value = '2';
            app.Loc_OtherCompute = uibutton(app.Grid_Loc, 'push');
            app.Loc_OtherCompute.ButtonPushedFcn = createCallbackFcn(app, @Loc_OtherButtonPushed, true);
            app.Loc_OtherCompute.Text = 'Define using alternative format';
            app.Panel_Sky = uipanel(app.Grid_Inputs);
            app.Panel_Sky.Title = 'Set Sky Model:';
            app.Grid_Sky = uigridlayout(app.Panel_Sky);
            app.Sky_Source = uidropdown(app.Grid_Sky);
            app.Sky_Source.Items = {'Sky Group A - Overcast Skies - Obscured Sun', 'Sky Group B - Partly Cloudy Skies - Unobscured Sun', 'Sky Group C - Clear Skies', 'Sky Group D - Clear (Polluted) Skies', 'CIE Sky Model 01 (I.1) - Standard Overcast Sky', 'CIE Sky Model 05 (III.1) - Uniform Luminance', 'CIE Sky Model 12 (V.4) - Standard Low-turbidity Clear Sky', 'CIE Sky Model 13 (V.5) - Standard Polluted Clear Sky'};
            app.Sky_Source.ItemsData = {'A', 'B', 'C', 'D', '01', '05', '12', '13'};
            app.Sky_Source.Value = 'C';
            app.Panel_Fun = uipanel(app.Grid_Inputs);
            app.GridLayout = uigridlayout(app.Panel_Fun);
            app.Fun_Run = uibutton(app.GridLayout, 'push');
            app.Fun_Run.ButtonPushedFcn = createCallbackFcn(app, @Run_Pushed, true);
            app.Fun_Run.Text = {'Load Data for'; 'Selected Sky Model'};
            app.Fun_Clear = uibutton(app.GridLayout, 'push');
            app.Fun_Clear.ButtonPushedFcn = createCallbackFcn(app, @Clear_Pushed, true);
            app.Fun_Clear.Enable = 'off';
            app.Fun_Clear.Text = 'Clear Data';
            app.Panel_Stats = uipanel(app.Grid_Inputs);
            app.Panel_Stats.Title = 'Select Column Density Statistic:';
            app.Grid_Stats = uigridlayout(app.Panel_Stats);
            app.Stats_ToPlot_Label = uilabel(app.Grid_Stats);
            app.Stats_ToPlot_Label.Text = 'Plotted Statistic:';
            app.Stats_ToPlot = uidropdown(app.Grid_Stats);
            app.Stats_ToPlot.Items = {'Absolute Value: Central Tendency', 'Relative Uncertainty: Coefficient of Variation', 'Relative Uncertainty: 95% Confidence Interval (Tail-Averaged)', 'Relative Uncertainty: 95% Confidence Interval (Lower Tail)', 'Relative Uncertainty: 95% Confidence Interval (Upper Tail)'};
            app.Stats_ToPlot.ItemsData = {'1', '2', '3', '4', '5'};
            app.Stats_ToPlot.ValueChangedFcn = createCallbackFcn(app, @Stats_ValueChanged, true);
            app.Stats_ToPlot.Value = '2';
            app.Stats_ctr_Label = uilabel(app.Grid_Stats);
            app.Stats_ctr_Label.Text = 'Central Tendency:';
            app.Stats_ctr = uidropdown(app.Grid_Stats);
            app.Stats_ctr.Items = {'Average', 'Median'};
            app.Stats_ctr.ItemsData = {'1', '2'};
            app.Stats_ctr.ValueChangedFcn = createCallbackFcn(app, @Stats_ValueChanged, true);
            app.Stats_ctr.Value = '1';
            app.Stats_var_Label = uilabel(app.Grid_Stats);
            app.Stats_var_Label.Text = 'Variability:';
            app.Stats_var = uidropdown(app.Grid_Stats);
            app.Stats_var.Items = {'Standard Deviation (SD)', 'Equivalent SD: 95% C.I.', 'Equivalent SD: Median Absolute Deviation'};
            app.Stats_var.ItemsData = {'1', '2', '3'};
            app.Stats_var.ValueChangedFcn = createCallbackFcn(app, @Stats_ValueChanged, true);
            app.Stats_var.Value = '2';
            app.Panel_Cam = uipanel(app.Grid_Inputs);
            app.Panel_Cam.Title = 'Define Camera/Sensor:';
            app.Grid_Cam = uigridlayout(app.Panel_Cam);
            app.Cam_Source_Label = uilabel(app.Grid_Cam);
            app.Cam_Source_Label.Enable = 'off';
            app.Cam_Source_Label.Text = 'Select Sensor:';
            app.Cam_Source = uidropdown(app.Grid_Cam);
            app.Cam_Source.Items = {'Andor Neo 5.5', 'Andor Zyla 5.5', 'pco.edge 3.1', 'pco.edge 5.5', 'pco.edge 26', 'Manually Defined'};
            app.Cam_Source.ItemsData = {'CIS2521', 'CIS2521', 'CIS2521mod', 'CIS2521', 'GMAX0505', 'Manual'};
            app.Cam_Source.ValueChangedFcn = createCallbackFcn(app, @Cam_SourceChanged, true);
            app.Cam_Source.Enable = 'off';
            app.Cam_Source.Value = 'CIS2521';
            app.Cam_Pitch_Label = uilabel(app.Grid_Cam);
            app.Cam_Pitch_Label.Enable = 'off';
            app.Cam_Pitch_Label.Text = 'Pixel Pitch [um]:';
            app.Cam_Pitch = uispinner(app.Grid_Cam);
            app.Cam_Pitch.Step = 0.1;
            app.Cam_Pitch.LowerLimitInclusive = 'off';
            app.Cam_Pitch.Limits = [0 15];
            app.Cam_Pitch.ValueDisplayFormat = '%.1f';
            app.Cam_Pitch.ValueChangedFcn = createCallbackFcn(app, @Cam_ValueChanged, true);
            app.Cam_Pitch.Enable = 'off';
            app.Cam_Pitch.Value = 6.5;
            app.Cam_Width_Label = uilabel(app.Grid_Cam);
            app.Cam_Width_Label.Enable = 'off';
            app.Cam_Width_Label.Text = 'Sensor Width [px]:';
            app.Cam_Width = uispinner(app.Grid_Cam);
            app.Cam_Width.Limits = [256 5120];
            app.Cam_Width.RoundFractionalValues = 'on';
            app.Cam_Width.ValueDisplayFormat = '%u';
            app.Cam_Width.ValueChangedFcn = createCallbackFcn(app, @Cam_ValueChanged, true);
            app.Cam_Width.Enable = 'off';
            app.Cam_Width.Value = 2560;
            app.Cam_Focal_Label = uilabel(app.Grid_Cam);
            app.Cam_Focal_Label.Enable = 'off';
            app.Cam_Focal_Label.Text = 'Focal Length [mm]:';
            app.Cam_Focal = uispinner(app.Grid_Cam);
            app.Cam_Focal.Limits = [8 1000];
            app.Cam_Focal.RoundFractionalValues = 'on';
            app.Cam_Focal.ValueDisplayFormat = '%u';
            app.Cam_Focal.ValueChangedFcn = createCallbackFcn(app, @Cam_ValueChanged, true);
            app.Cam_Focal.Enable = 'off';
            app.Cam_Focal.Value = 50;
            app.Cam_Height_Label = uilabel(app.Grid_Cam);
            app.Cam_Height_Label.Enable = 'off';
            app.Cam_Height_Label.Text = 'Sensor Height [px]:';
            app.Cam_Height = uispinner(app.Grid_Cam);
            app.Cam_Height.Limits = [256 5120];
            app.Cam_Height.RoundFractionalValues = 'on';
            app.Cam_Height.ValueDisplayFormat = '%u';
            app.Cam_Height.ValueChangedFcn = createCallbackFcn(app, @Cam_ValueChanged, true);
            app.Cam_Height.Enable = 'off';
            app.Cam_Height.Value = 2160;
            app.Grid_Outputs = uigridlayout(app.Grid_Main);
            app.Panel_Results = uipanel(app.Grid_Outputs);
            app.Panel_Results.Title = 'Plotted Statistic:';
            app.Panel_Interactivity = uipanel(app.Grid_Outputs);
            app.Panel_Interactivity.Title = 'On-the-Fly Controls:';
            app.Grid_Interactivity = uigridlayout(app.Panel_Interactivity);
            app.IntEdit_Plume_Label = uilabel(app.Grid_Interactivity);
            app.IntEdit_Plume_Label.Enable = 'off';
            app.IntEdit_Plume_Label.Text = 'Plume Transmittance:';
            app.IntEdit_Plume = uieditfield(app.Grid_Interactivity, 'numeric');
            app.IntEdit_Plume.Limits = [25 95];
            app.IntEdit_Plume.RoundFractionalValues = 'on';
            app.IntEdit_Plume.ValueDisplayFormat = '%02u%%';
            app.IntEdit_Plume.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntEdit_Plume.Enable = 'off';
            app.IntEdit_Plume.Value = 75;
            app.IntEdit_Wind_Label = uilabel(app.Grid_Interactivity);
            app.IntEdit_Wind_Label.Enable = 'off';
            app.IntEdit_Wind_Label.Text = 'Wind Direction:';
            app.IntEdit_Wind = uieditfield(app.Grid_Interactivity, 'numeric');
            app.IntEdit_Wind.Limits = [0 360];
            app.IntEdit_Wind.ValueDisplayFormat = '%03u\x00b0';
            app.IntEdit_Wind.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntEdit_Wind.Enable = 'off';
            app.IntEdit_Cont_Label = uilabel(app.Grid_Interactivity);
            app.IntEdit_Cont_Label.Enable = 'off';
            app.IntEdit_Cont_Label.Text = 'Data Contour:';
            app.IntEdit_Cont = uieditfield(app.Grid_Interactivity, 'numeric');
            app.IntEdit_Cont.Limits = [10 50];
            app.IntEdit_Cont.ValueDisplayFormat = '%04.1f%%';
            app.IntEdit_Cont.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntEdit_Cont.Enable = 'off';
            app.IntEdit_Cont.Value = 30;
            app.IntEdit_Time_Label = uilabel(app.Grid_Interactivity);
            app.IntEdit_Time_Label.Enable = 'off';
            app.IntEdit_Time_Label.Text = 'Time Adjustment:';
            app.IntEdit_Time = uieditfield(app.Grid_Interactivity, 'text');
            app.IntEdit_Time.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntEdit_Time.Enable = 'off';
            app.IntEdit_Time.Value = '12:00';
            app.IntSlide_Plume = uislider(app.Grid_Interactivity);
            app.IntSlide_Plume.Limits = [25 95];
            app.IntSlide_Plume.MajorTicks = [];
            app.IntSlide_Plume.MajorTickLabels = {''};
            app.IntSlide_Plume.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntSlide_Plume.MinorTicks = [];
            app.IntSlide_Plume.Enable = 'off';
            app.IntSlide_Plume.Value = 75;
            app.IntSlide_Wind = uislider(app.Grid_Interactivity);
            app.IntSlide_Wind.Limits = [0 360];
            app.IntSlide_Wind.MajorTicks = [];
            app.IntSlide_Wind.MajorTickLabels = {''};
            app.IntSlide_Wind.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntSlide_Wind.MinorTicks = [];
            app.IntSlide_Wind.Enable = 'off';
            app.IntSlide_Cont = uislider(app.Grid_Interactivity);
            app.IntSlide_Cont.Limits = [10 50];
            app.IntSlide_Cont.MajorTicks = [];
            app.IntSlide_Cont.MajorTickLabels = {''};
            app.IntSlide_Cont.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntSlide_Cont.MinorTicks = [];
            app.IntSlide_Cont.Enable = 'off';
            app.IntSlide_Cont.Value = 30;
            app.IntSlide_Time = uislider(app.Grid_Interactivity);
            app.IntSlide_Time.Limits = [0 1];
            app.IntSlide_Time.MajorTicks = [];
            app.IntSlide_Time.MajorTickLabels = {''};
            app.IntSlide_Time.ValueChangedFcn = createCallbackFcn(app, @Int_ValueChanged, true);
            app.IntSlide_Time.MinorTicks = [];
            app.IntSlide_Time.Enable = 'off';
            app.IntSlide_Time.Value = 0.5;
            app.Grid_Meta = uigridlayout(app.Grid_Outputs);
            app.Panel_Sun = uipanel(app.Grid_Meta);
            app.Panel_Sun.Title = 'Solar Position:';
            app.Grid_Sun = uigridlayout(app.Panel_Sun);
            app.Sun_Rise_Label = uilabel(app.Grid_Sun);
            app.Sun_Rise_Label.Text = 'Local Sunrise:';
            app.Sun_Rise = uilabel(app.Grid_Sun);
            app.Sun_Rise.Text = '- - - - -';
            app.Sun_Set_Label = uilabel(app.Grid_Sun);
            app.Sun_Set_Label.Text = 'Local Sunset:';
            app.Sun_Set = uilabel(app.Grid_Sun);
            app.Sun_Set.Text = '- - - - -';
            app.Sun_Azi_Label = uilabel(app.Grid_Sun);
            app.Sun_Azi_Label.Text = 'Current Azimuth:';
            app.Sun_Azi = uilabel(app.Grid_Sun);
            app.Sun_Azi.Text = '- - - - -';
            app.Sun_Zen_Label = uilabel(app.Grid_Sun);
            app.Sun_Zen_Label.Text = 'Current Zenith:';
            app.Sun_Zen = uilabel(app.Grid_Sun);
            app.Sun_Zen.Text = '- - - - -';
            app.Panel_Cursor = uipanel(app.Grid_Meta);
            app.Panel_Cursor.Title = 'Cursor Position:';
            app.Grid_Cursor = uigridlayout(app.Panel_Cursor);
            app.Current_Label = uilabel(app.Grid_Cursor);
            app.Current_Label.Text = 'Current';
            app.Selected_Label = uilabel(app.Grid_Cursor);
            app.Selected_Label.Text = 'Selected';
            app.Cursor_Azi_Label = uilabel(app.Grid_Cursor);
            app.Cursor_Azi_Label.Text = 'Camera Bearing (from N):';
            app.Current_Azi = uilabel(app.Grid_Cursor);
            app.Current_Azi.Text = '- - - - -';
            app.Cursor_Inc_Label = uilabel(app.Grid_Cursor);
            app.Cursor_Inc_Label.Text = 'Camera Inclination:';
            app.Current_Inc = uilabel(app.Grid_Cursor);
            app.Current_Inc.Text = '- - - - -';
            app.Cursor_SunTheta_Label = uilabel(app.Grid_Cursor);
            app.Cursor_SunTheta_Label.Text = 'Sun Scattering Angle:';
            app.Current_SunTheta = uilabel(app.Grid_Cursor);
            app.Current_SunTheta.Text = '- - - - -';
            app.Selected_SunTheta = uilabel(app.Grid_Cursor);
            app.Selected_SunTheta.Text = '- - - - -';
            app.Cursor_Dat_Label = uilabel(app.Grid_Cursor);
            app.Cursor_Dat_Label.Text = 'Relative Uncertainty:';
            app.Current_Dat = uilabel(app.Grid_Cursor);
            app.Current_Dat.Text = '- - - - -';
            app.Selected_Dat = uilabel(app.Grid_Cursor);
            app.Selected_Dat.Text = '- - - - -';
            app.Selected_Azi = uispinner(app.Grid_Cursor);
            app.Selected_Azi.Step = 0.5;
            app.Selected_Azi.Limits = [0 360];
            app.Selected_Azi.ValueDisplayFormat = '%.1f\x00b0';
            app.Selected_Azi.ValueChangedFcn = createCallbackFcn(app, @SelectedPointing_ValueChanged, true);
            app.Selected_Azi.Enable = 'off';
            app.Selected_Azi.Value = 180;
            app.Selected_Inc = uispinner(app.Grid_Cursor);
            app.Selected_Inc.Step = 0.5;
            app.Selected_Inc.Limits = [0 90];
            app.Selected_Inc.ValueDisplayFormat = '%.1f\x00b0';
            app.Selected_Inc.ValueChangedFcn = createCallbackFcn(app, @SelectedPointing_ValueChanged, true);
            app.Selected_Inc.Enable = 'off';
            app.Selected_Inc.Value = 10;
            app.Panel_PlotVis = uipanel(app.Grid_Meta);
            app.Panel_PlotVis.Title = 'Plot Visibility:';
            app.Grid_PlotVis = uigridlayout(app.Panel_PlotVis);
            app.PlotVis_Sun = uibutton(app.Grid_PlotVis, 'state');
            app.PlotVis_Sun.ValueChangedFcn = createCallbackFcn(app, @PlotVis_ValueChanged, true);
            app.PlotVis_Sun.Enable = 'off';
            app.PlotVis_Sun.Text = 'Daily/Current Solar Position';
            app.PlotVis_Sun.Value = true;
            app.PlotVis_Con = uibutton(app.Grid_PlotVis, 'state');
            app.PlotVis_Con.ValueChangedFcn = createCallbackFcn(app, @PlotVis_ValueChanged, true);
            app.PlotVis_Con.Enable = 'off';
            app.PlotVis_Con.Text = 'Data Contour';
            app.PlotVis_Con.Value = true;
            app.PlotVis_Cam = uibutton(app.Grid_PlotVis, 'state');
            app.PlotVis_Cam.ValueChangedFcn = createCallbackFcn(app, @PlotVis_ValueChanged, true);
            app.PlotVis_Cam.Enable = 'off';
            app.PlotVis_Cam.Text = 'Image Extent (pinhole model)';
            app.PlotVis_Cam.Value = true;
            app.PlotVis_Win = uibutton(app.Grid_PlotVis, 'state');
            app.PlotVis_Win.ValueChangedFcn = createCallbackFcn(app, @PlotVis_ValueChanged, true);
            app.PlotVis_Win.Enable = 'off';
            app.PlotVis_Win.Text = 'Orthogonal to Wind';
            app.Grid_Images = uigridlayout(app.Grid_Outputs);
            app.Im_Carleton = uiimage(app.Grid_Images);
            app.Im_Carleton.ImageClickedFcn = createCallbackFcn(app, @Im_GeneralClicked, true);
            app.Im_EERL = uiimage(app.Grid_Images);
            app.Im_EERL.ImageClickedFcn = createCallbackFcn(app, @Im_GeneralClicked, true);
            app.Im_FlareNet = uiimage(app.Grid_Images);
            app.Im_FlareNet.ImageClickedFcn = createCallbackFcn(app, @Im_GeneralClicked, true);
            app.Grid_Utilities = uigridlayout(app.Grid_Images);
            app.Maximizer_Button = uibutton(app.Grid_Utilities, 'push');
            app.Maximizer_Button.ButtonPushedFcn = createCallbackFcn(app, @Menu_ComputePeriodMax_Fun, true);
            app.Maximizer_Button.Enable = 'off';
            app.Maximizer_Button.Text = 'Run "Maximizer" Utility';
            app.Positioner_Button = uibutton(app.Grid_Utilities, 'push');
            app.Positioner_Button.ButtonPushedFcn = createCallbackFcn(app, @Menu_ComputePermissibleLoc_Fun, true);
            app.Positioner_Button.Enable = 'off';
            app.Positioner_Button.Text = 'Run "Positioner" Utility';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetupSkyLOSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @Figure_Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end