classdef SetupSkyLOSA_MaximizerExitInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure             matlab.ui.Figure
        Grid_Main          matlab.ui.container.GridLayout
        Im_Icon            matlab.ui.control.Image
        Label_Title        matlab.ui.control.Label
        Label_Text         matlab.ui.control.Label
        Check_Acknowledge  matlab.ui.control.CheckBox
        Fun_Run            matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp      % Calling application
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Figure_Startup(app, varargin)
            % This startup function detects the calling app
            
            % Detect calling application
            if nargin == 2
                if isa(varargin{1}, 'SetupSkyLOSA')
                    app.CallingApp = varargin{1};
                    
                    % Load image data
                    app.Im_Icon.ImageSource = fullfile(app.CallingApp.folder_base, 'images', 'MATLAB_InfoIcon.png');
                else
                    delete(app)
                    return
                end
            else
                delete(app)
                return
            end
            
            % Make calling app uninteractable
            app.CallingApp.status.Interactable = 0;
            
            % Center window in calling object
            app.Figure.Position(1) = app.CallingApp.Figure.Position(1) + app.CallingApp.Figure.Position(3) / 2 - app.Figure.Position(3) / 2;
            app.Figure.Position(2) = app.CallingApp.Figure.Position(2) + app.CallingApp.Figure.Position(4) / 2 - app.Figure.Position(4) / 2;
        end

        % Button pushed function: Fun_Run
        function Fun_Submit(app, event)
            % This callback updates the calling app's acknowledgement of
            % the warning and closes the app
            
            app.CallingApp.status.Interactable = 1;
            app.CallingApp.status.MaximizerExitAck = app.Check_Acknowledge.Value;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Grid_Main = uigridlayout(app.Figure);
            app.Im_Icon = uiimage(app.Grid_Main);
            app.Label_Title = uilabel(app.Grid_Main);
            app.Label_Title.Text = '  Showing Maximized Data';
            app.Label_Text = uilabel(app.Grid_Main);
            app.Label_Text.Text = {'The app now shows the relative uncertainty statistic maximized'; 'over the selected time period.'; ''; 'To revert back to plotting statistics at a specific time:'; 'change the selected column density statistic or '; 'plume transmittance (highlighted in red) or press "clear data".'};
            app.Check_Acknowledge = uicheckbox(app.Grid_Main);
            app.Check_Acknowledge.Text = 'Don''t warn me again';
            app.Fun_Run = uibutton(app.Grid_Main, 'push');
            app.Fun_Run.ButtonPushedFcn = createCallbackFcn(app, @Fun_Submit, true);
            app.Fun_Run.Text = 'Okay';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetupSkyLOSA_MaximizerExitInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)Figure_Startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end