classdef SetupSkyLOSA_ObtainMaximumData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure           matlab.ui.Figure
        Grid_Main        matlab.ui.container.GridLayout
        Grid_Main_Label  matlab.ui.control.Label
        Panel_Lat        matlab.ui.container.Panel
        Fun_Run          matlab.ui.control.Button
        Grid_Lat         matlab.ui.container.GridLayout
        Start_Label      matlab.ui.control.Label
        Finis_Label      matlab.ui.control.Label
        Start_Text       matlab.ui.control.EditField
        Finis_Text       matlab.ui.control.EditField
        ButtonGroup      matlab.ui.container.ButtonGroup
        MaxOver_Current  matlab.ui.control.RadioButton
        MaxOver_Groups   matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        CallingApp      % Calling application
    end
    
    methods (Access = private)
        function [] = sub_TimedProgressBar(~, frac, hdlg, tstart)
            
            % Get elapsed and remaining time
            elap = toc(tstart);
            remn = elap * (1 / frac - 1);
            
            % Format elapsed time
            if elap > 5000
                msg1 = sprintf('%05.2f Hours Elapsed - ',elap/3600);
            elseif elap > 300
                msg1 = sprintf('%05.2f Minutes Elapsed - ',elap/60);
            else
                msg1 = sprintf('%05.1f Seconds Elapsed - ',elap);
            end
            
            % Format remaining time
            if remn > 5000
                msg2 = sprintf('%05.2f Hours Remain',remn/3600);
            elseif remn > 300
                msg2 = sprintf('%05.2f Minutes Remain',remn/60);
            else
                msg2 = sprintf('%05.1f Seconds Remain',remn);
            end
            
            % Update progress bar
            hdlg.Value = frac;
            hdlg.Message = [msg1, msg2];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Figure_StartFcn(app, caller)
            % This startup function detects the calling source and loads
            % existing GPS data, as necessarcy
            
            app.CallingApp = caller;
            
            % Center window in calling object
            app.Figure.Position(1) = app.CallingApp.Figure.Position(1) + app.CallingApp.Figure.Position(3) / 2 - app.Figure.Position(3) / 2;
            app.Figure.Position(2) = app.CallingApp.Figure.Position(2) + app.CallingApp.Figure.Position(4) / 2 - app.Figure.Position(4) / 2;
            
            % Update current sky text
            app.MaxOver_Current.Text = strrep(app.MaxOver_Current.Text, 'XX', app.CallingApp.Sky_Source.Value);
            
            % Update times
            app.Start_Text.Value = app.CallingApp.Sun_Rise.Text;
            app.Finis_Text.Value = app.CallingApp.Sun_Set.Text;
        end

        % Value changed function: Finis_Text, Start_Text
        function Time_ValueChange(app, event)
            
            % Error check time input and update accordingly
            try
                datenum(event.Value, 'HH:MM');
                event.Source.Value = datestr(mod(datenum(event.Value), 1), 'HH:MM');
            catch % Revert to previous time
                event.Source.Value = event.PreviousValue;
            end
            
            % Compare
            start = datenum(app.Start_Text.Value);
            finis = datenum(app.Finis_Text.Value);
            
            if finis < start
                switch event.Source
                    case app.Start_Text
                        app.Start_Text.Value = app.Finis_Text.Value;
                    case app.Finis_Text
                        app.Finis_Text.Value = app.Start_Text.Value;
                end
            end
        end

        % Button pushed function: Fun_Run
        function Fun_Compute(app, event)
            % This callback runs the submit changes function and closes the
            % app
            
            % Get period (by index (1 : 1441)
            start = round(mod(datenum(app.Start_Text.Value, 'HH:MM'), 1) * 1440) + 1;
            finis = round(mod(datenum(app.Finis_Text.Value, 'HH:MM'), 1) * 1440) + 1;
            
            % Pull sun position over day
            azi = app.CallingApp.sunpos.DailyAzi;
            zen = app.CallingApp.sunpos.DailyZen;
            
            % Interp data
            xx = sind(zen) .* cosd(azi);
            yy = sind(zen) .* sind(azi);
            zz = cosd(zen);
            idx = find(and(~isnan(zen), ~isnan(azi)));
            idx = idx(and(idx >= start, idx <= finis));
            tt = (idx(1) : 0.05 : idx(end))';
            if length(idx) == 1
                xx = xx(idx);
                yy = yy(idx);
                zz = zz(idx);
            else
                xx = interp1(idx, xx(idx), tt, 'makima', 'extrap');
                yy = interp1(idx, yy(idx), tt, 'makima', 'extrap');
                zz = interp1(idx, zz(idx), tt, 'makima', 'extrap');
            end
            zen = acosd(zz);
            azi = 180 + atan2d(-yy, -xx);
            clear tt xx yy zz fx fy fz idx
            
            % Obtain image data
            hdlg = uiprogressdlg(app.Figure, 'icon', 'info', 'Indeterminate', 1);
            set(hdlg, 'Title', 'Loading Data', 'Message', 'Loading...', 'ShowPercentage', 1)
            set(hdlg, 'Cancelable', 1, 'CancelText', 'Abort')
            if app.MaxOver_Current.Value
                skydat = app.CallingApp.sky.data;
                
            elseif app.MaxOver_Groups.Value
                skydat = [];
                loadfiles = cellfun(@(x) fullfile(app.CallingApp.folder_base, 'data', ['SkyType_', x, '.mat']), {'A', 'B', 'C', 'D'}, 'uni', 0);
                tmp = cellfun(@(x) load(x, 'stats'), loadfiles, 'uni', 0);
                skydat.P = tmp{1}.stats.P;
                skydat.T = tmp{1}.stats.T;
                skydat.ave = cell2mat(arrayfun(@(x) tmp{x}.stats.ave, permute((1 : 4)', [6 5 4 3 2 1]), 'UniformOutput', false));
                skydat.prc = cell2mat(arrayfun(@(x) tmp{x}.stats.prc, permute((1 : 4)', [6 5 4 3 2 1]), 'UniformOutput', false));
                skydat.mad = cell2mat(arrayfun(@(x) tmp{x}.stats.mad, permute((1 : 4)', [6 5 4 3 2 1]), 'UniformOutput', false));
                skydat.sig = cell2mat(arrayfun(@(x) tmp{x}.stats.sig, permute((1 : 4)', [6 5 4 3 2 1]), 'UniformOutput', false));
                clear tmp
            end
            
            % Central tendency
            switch app.CallingApp.Stats_ctr.Value
                case 1
                    ctr = skydat.ave;
                case 2
                    pidx = abs(skydat.P - 50.0) < 1e-6;
                    ctr = skydat.prc(:, :, :, :, pidx, :);
            end
            
            % Statistic
            switch app.CallingApp.Stats_ToPlot.Items{app.CallingApp.Stats_ToPlot.Value}
                case 'Relative Uncertainty: Coefficient of Variation'
                    switch app.CallingApp.Stats_var.Value
                        case 1
                            sig_equiv = skydat.sig;
                        case 2
                            p1 = abs(skydat.P - 97.5) < 1e-6;
                            p2 = abs(skydat.P - 02.5) < 1e-6;
                            sig_equiv = (skydat.prc(:, :, :, :, p1, :) - skydat.prc(:, :, :, :, p2, :)) / 2 / norminv(0.975);
                        case 3
                            sig_equiv = skydat.mad / norminv(0.75);
                    end
                case 'Relative Uncertainty: 95% Confidence Interval (Tail-Averaged)'
                    p1 = abs(skydat.P - 97.5) < 1e-6;
                    p2 = abs(skydat.P - 02.5) < 1e-6;
                    sig_equiv = (skydat.prc(:, :, :, :, p1, :) - skydat.prc(:, :, :, :, p2, :)) / 2;
                case 'Relative Uncertainty: 95% Confidence Interval (Lower Tail)'
                    p2 = abs(skydat.P - 02.5) < 1e-6;
                    sig_equiv = ctr - skydat.prc(:, :, :, :, p2, :);
                case 'Relative Uncertainty: 95% Confidence Interval (Upper Tail)'
                    p1 = abs(skydat.P - 97.5) < 1e-6;
                    sig_equiv = skydat.prc(:, :, :, :, p1, :) - ctr;
                otherwise
                    return
            end
            unc = sig_equiv ./ ctr * 100;
            
            % Peform interpolation over transmittance as needed (dim 4)
            idx_eq = find(abs(app.CallingApp.sky.tv - app.CallingApp.IntSlide_Plume.Value) < 1e-6);
            if ~isempty(idx_eq) % Interpolation not needed
                unc = unc(:, :, :, idx_eq, :, :);
                
            else % Interpolation needed
                % Get k-index and data used regardless of index
                k = find(app.CallingApp.sky.tv < app.CallingApp.IntSlide_Plume.Value, 1, 'last');
                pk = unc(:, :, :, k, :, :);
                pkp1 = unc(:, :, :, k + 1, :, :);
                tk = app.CallingApp.sky.tv(k);
                tkp1 = app.CallingApp.sky.tv(k + 1);
                
                % Get slope before point
                if k == 1 % Forward difference for mk
                    mk = (pkp1 - pk) / (tkp1 - tk);
                    
                else % central difference about pk
                    pkm1 = unc(:, :, :, k - 1, :, :);
                    tkm1 = app.CallingApp.sky.tv(k - 1);
                    mk = 0.5 * ((pkp1 - pk) / (tkp1 - tk) + (pk - pkm1) / (tk - tkm1));
                end
                
                % Get slope after point
                if k == (length(app.CallingApp.sky.tv) - 1) % Backwards different for mkp1
                    mkp1 = (pkp1 - pk) / (tkp1 - tk);
                    
                else
                    pkp2 = unc(:, :, :, k + 2, :, :);
                    tkp2 = app.CallingApp.sky.tv(k + 2);
                    mkp1 = 0.5 * ((pkp2 - pkp1) / (tkp2 - tkp1) + (pkp1 - pk) / (tkp1 - tk));
                end
                
                % Compute position
                t = (app.CallingApp.IntSlide_Plume.Value - tk) / (tkp1 - tk);
                
                % Compute Hermite bases
                h00 = polyval([+2 -3 0 1], t);
                h10 = polyval([+1 -2 1 0], t);
                h01 = polyval([-2 +3 0 0], t);
                h11 = polyval([+1 -1 0 0], t);
                
                % Perform interp
                unc = h00 .* pk + h10 * (tkp1 - tk) .* mk + h01 .* pkp1 + h11 .* (tkp1 - tk) .* mkp1;
            end
            unc = squeeze(unc);
            
            % Create interpolant
            if app.MaxOver_Current.Value
                f = griddedInterpolant({app.CallingApp.sky.bv(:), app.CallingApp.sky.av(:), app.CallingApp.sky.zv(:)}, unc, 'spline', 'none');
            elseif app.MaxOver_Groups.Value
                f = griddedInterpolant({app.CallingApp.sky.bv(:), app.CallingApp.sky.av(:), app.CallingApp.sky.zv(:), (1 : 4)'}, unc, 'spline', 'none');
            end
            
            % Get maximal data
            [bg, ag] = ndgrid(0 : 2 : 90, 0 : 2 : 360);
            unc_max = zeros(size(bg, 1), size(bg, 2), size(unc, 4)) + nan;
            set(hdlg, 'Value', 0, 'Indeterminate', 0, 'Title', 'Computing Period-Maximum')
            tstart = tic;
            k = 0;
            azi = repmat(azi, [1 size(unc, 4)]);
            zen = repmat(zen, [1 size(unc, 4)]);
            for bidx = 1 : size(bg, 1)
                for aidx = 1 : size(ag, 2)
                    k = k + 1;
                    bet = bg(bidx, 1);
                    asN = ag(1, aidx);
                    if app.MaxOver_Current.Value
                        dat = f(bet * ones(size(zen, 1), size(unc, 4)), 180 - acosd(cosd(abs(asN - azi))), zen);
                        unc_max(bidx, aidx) = max(dat(:));
                    elseif app.MaxOver_Groups.Value
                        dat = f(bet * ones(size(zen, 1), size(unc, 4)), 180 - acosd(cosd(abs(asN - azi))), zen, (1 : size(unc, 4)) .* ones(size(azi)));
                        unc_max(bidx, aidx, :) = permute(max(dat, [], 1)', [3 2 1]);
                    end
                    
                    % Abort if requested
                    if ~isvalid(hdlg)
                        delete(hdlg)
                        return
                    end
                    if hdlg.CancelRequested
                        delete(hdlg)
                        return
                    end
                    sub_TimedProgressBar(app, k / numel(bg), hdlg, tstart)
                end
            end
            
            % Refine, smooth data, and plot
            unc_max = cell2mat(arrayfun(@(pg) interpn(unc_max(:, :, pg), 2, 'makima'), permute((1 : size(unc_max, 3))', [3 2 1]), 'uni', 0));
            unc_max = cell2mat(arrayfun(@(pg) imfilter(unc_max(:, :, pg), ones(11) / 11 ^ 2, 'replicate'), permute((1 : size(unc_max, 3))', [3 2 1]), 'uni', 0));
            unc_max = max(unc_max, [], 3);
            app.CallingApp.plotdata.h_image.CData = unc_max;
            app.CallingApp.ismax = 1;
            app.CallingApp.ismax_allgroup = app.MaxOver_Groups.Value;
            delete(hdlg)
            
            uiresume(app.CallingApp.Figure)
            delete(app)
        end

        % Close request function: Figure
        function Figure_CloseFcn(app, event)
            % This callback re-enables the calling button and closes the
            % app with a cancelled flag
            app.CallingApp.DialogCancelled = 1;
            uiresume(app.CallingApp.Figure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Period Input';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @Figure_CloseFcn, true);
            app.Grid_Main = uigridlayout(app.Figure);
            app.Grid_Main_Label = uilabel(app.Grid_Main);
            app.Grid_Main_Label.Text = 'Input Time Period (hh:mm)';
            app.Panel_Lat = uipanel(app.Grid_Main);
            app.Fun_Run = uibutton(app.Grid_Main, 'push');
            app.Fun_Run.ButtonPushedFcn = createCallbackFcn(app, @Fun_Compute, true);
            app.Fun_Run.Text = 'Compute Maximum Uncertainty';
            app.Grid_Lat = uigridlayout(app.Grid_Main);
            app.Start_Label = uilabel(app.Grid_Lat);
            app.Start_Label.Text = 'Period Start:';
            app.Finis_Label = uilabel(app.Grid_Lat);
            app.Finis_Label.Text = 'Period End:';
            app.Start_Text = uieditfield(app.Grid_Lat, 'text');
            app.Start_Text.ValueChangedFcn = createCallbackFcn(app, @Time_ValueChange, true);
            app.Start_Text.Value = '00:00';
            app.Finis_Text = uieditfield(app.Grid_Lat, 'text');
            app.Finis_Text.ValueChangedFcn = createCallbackFcn(app, @Time_ValueChange, true);
            app.Finis_Text.Value = '23:59';
            app.ButtonGroup = uibuttongroup(app.Grid_Main);
            app.MaxOver_Current = uiradiobutton(app.ButtonGroup);
            app.MaxOver_Current.Text = 'Maximize over currently selected sky (XX)';
            app.MaxOver_Current.Value = true;
            app.MaxOver_Groups = uiradiobutton(app.ButtonGroup);
            app.MaxOver_Groups.Text = 'Maximize over sky groups (A-D)';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetupSkyLOSA_ObtainMaximumData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)Figure_StartFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end