classdef SetupSkyLOSA_PlotPermissibleLocation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure              matlab.ui.Figure
        Grid_Main           matlab.ui.container.GridLayout
        Panel_Inputs        matlab.ui.container.Panel
        Grid_Inputs         matlab.ui.container.GridLayout
        Stack_Label         matlab.ui.control.Label
        Standoff_Label      matlab.ui.control.Label
        Thresh_Label        matlab.ui.control.Label
        Stack_Data          matlab.ui.control.Spinner
        Standoff_Data       matlab.ui.control.Spinner
        Thresh_Data         matlab.ui.control.Spinner
        Grid_Main_Label     matlab.ui.container.GridLayout
        Grid_Main_Title     matlab.ui.control.Label
        Grid_Main_Subtitle  matlab.ui.control.Label
        Grid_SaveToKML      matlab.ui.container.GridLayout
        Button_SaveToKML    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp      % Calling application
    end
    
    properties (Access = public)
        Ax % Axis for plotting of data
        Lg % Axis for legent
        RadMap % Map of radial distance [m]
        AziMap % Map of camera azimuth from N [deg]
        BetMap % Map of camera inclination [deg]
        h_ord % Handles for NESW ordinates plots
        h_ordtext % Handles for NESW ordinates text
        h_rad % Handles for radii plots
        h_radtext % Handles for radii test
        h_image % Handle for image data
        h_wind % Handles for orthogonal to wind
        h_cursor % Handles to selected position
    end
    
    methods (Access = private)
        
        function [lat, lon] = Vincenty_Forward(~, lat, lon, del, azi)
            % This function uses Vincenty's direct/forward problem to
            % jitter input coordinates a given horizontal distance and
            % heading
            
            % Define constants
            a = 6378137.0; % Radius at equator [m]
            f = 1 / 298.257223563; % Ellipsoid flattening [-]
            b = (1 - f) * a; % Radius at poles [m]
            
            % Convert angles to radians
            lat = lat * pi / 180;
            lon = mod(lon, 360) * pi / 180;
            azi = azi * pi / 180;
            
            % Run through algorithm initialization
            U1 = atan((1 - f) * tan(lat));
            sig_1 = atan2(tan(U1), cos(azi));
            sin_a = cos(U1) .* sin(azi);
            u2 = (1 - sin_a .^ 2) .* ((a ./ b) .^ 2 - 1);
            k1 = (sqrt(1 + u2) - 1) ./ (sqrt(1 + u2) + 1);
            A = (1 + 0.25 * k1 .^ 2) ./ (1 - k1);
            B = k1 .* (1 - 3 / 8 * k1 .^ 2);
            
            % Run iteration
            sig = del / b ./ A;
            ds_old = inf;
            while 1
                c2sm = cos(2 * sig_1 + sig);
                del_sig = B .* sin(sig) .* (c2sm + B / 4 .* (cos(sig) .* (-1 + 2 * c2sm .^ 2) - B / 6 .* c2sm .* (-3 + 4 * sin(sig) .^ 2) .* (-3 + 4 * c2sm .^ 2)));
                sig = del / b ./ A + del_sig;
                if max(abs(del_sig - ds_old)) < 1e-16
                    break
                end
                ds_old = del_sig;
            end
            c2sm = cos(2 * sig_1 + sig);
            
            % Get latitude and longitude of new point
            lat = atan2(sin(U1) .* cos(sig) + cos(U1) .* sin(sig) .* cos(azi), (1 - f) * sqrt(sin_a .^ 2 + (sin(U1) .* sin(sig) - cos(U1) .* cos(sig) .* cos(azi)) .^ 2));
            lambda = atan2(sin(sig) .* sin(azi), cos(U1) .* cos(sig) - sin(U1) .* sin(sig) .* cos(azi));
            C = f / 16 * (1 - sin_a .^ 2) .* (4 + f * (4 - 3 * (1 - sin_a .^ 2)));
            L = lambda - f * (1 - C) .* sin_a .* (sig + C .* sin(sig) .* (c2sm + C .* cos(sig) .* (-1 + 2 * c2sm .^ 2)));
            lon = lon + L;
            
            % Convert angles to degrees
            lat = lat * 180 / pi;
            lon = lon * 180 / pi;
            lon = lon - 360 * (lon > 180);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Figure_StartFcn(app, caller)
            % This startup function detects the calling source and loads
            % existing GPS data, as necessarcy
            
            app.CallingApp = caller;
            
            % Turn off warnings
            warning('off', 'MATLAB:polyshape:repairedBySimplify')
            warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
            
            % Center window in calling object
            app.Figure.Position(1) = app.CallingApp.Figure.Position(1) + app.CallingApp.Figure.Position(3) / 2 - app.Figure.Position(3) / 2;
            app.Figure.Position(2) = app.CallingApp.Figure.Position(2) + app.CallingApp.Figure.Position(4) / 2 - app.Figure.Position(4) / 2;
            
            % Update subtitle
            app.Grid_Main_Subtitle.Text = app.CallingApp.Cb_Results.Label.String;
            
            % Edit value and limits on threshold spinner
            app.Thresh_Data.Value = app.CallingApp.IntSlide_Cont.Value;
            lims(1, 1) = app.CallingApp.Cb_Results.Limits(1) + 0.1;
            lims(1, 2) = round(lims(1, 1) + diff(app.CallingApp.Cb_Results.Limits) * 246 / 256, 1, 'decimals');
            app.Thresh_Data.Limits = lims;
            
            % Create and format plotting axis
            app.Ax = uiaxes(app.Figure, 'Units', 'pixels', 'Position', [145 210 350 350]);
            app.Ax.Visible = 'on';
            app.Ax.NextPlot = 'add';
            app.Ax.Color = 'none';
            app.Ax.PlotBoxAspectRatio = [1 1 1];
            app.Ax.XLim = [-1 1];
            app.Ax.YLim = [-1 1];
            app.Ax.XTick = [];
            app.Ax.YTick = [];
            app.Ax.YDir = 'normal';
            app.Ax.XColor = 'none';
            app.Ax.YColor = 'none';
            
            % Plot dummy data - ordinates
            app.h_ord = gobjects(2, 1);
            app.h_ord(1) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_ord(2) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_ordtext = gobjects(4, 1);
            app.h_ordtext(1) = text(app.Ax, nan, nan, 'N', 'color', 'k', 'fontname', 'arial', 'fontsize', 18, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            app.h_ordtext(2) = text(app.Ax, nan, nan, 'E', 'color', 'k', 'fontname', 'arial', 'fontsize', 18, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle');
            app.h_ordtext(3) = text(app.Ax, nan, nan, 'S', 'color', 'k', 'fontname', 'arial', 'fontsize', 18, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'top');
            app.h_ordtext(4) = text(app.Ax, nan, nan, 'W', 'color', 'k', 'fontname', 'arial', 'fontsize', 18, 'HorizontalAlignment', 'right', 'VerticalAlignment', 'middle');
            
            % Plot dummy data - radii
            app.h_rad = gobjects(6, 1);
            app.h_rad(1) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_rad(2) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_rad(3) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_rad(4) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_rad(5) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_rad(6) = plot(app.Ax, nan, nan, 'k', 'linewidth', 0.5);
            app.h_radtext = gobjects(6, 1);
            app.h_radtext(1) = text(app.Ax, nan, nan, '', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'rotation', -45);
            app.h_radtext(2) = text(app.Ax, nan, nan, '', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'rotation', -45);
            app.h_radtext(3) = text(app.Ax, nan, nan, '', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'rotation', -45);
            app.h_radtext(4) = text(app.Ax, nan, nan, '', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'rotation', -45);
            app.h_radtext(5) = text(app.Ax, nan, nan, '', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'rotation', -45);
            app.h_radtext(6) = text(app.Ax, nan, nan, '', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'rotation', -45);
            set(app.h_radtext, 'backgroundcolor', app.Figure.Color, 'margin', 2)
            
            % Plot dummy data - regions
            app.h_image = image(app.Ax, zeros(501, 'uint8'), 'alphadata', zeros(501, 'single'));
            
            % Plot dummy data - wind
            app.h_wind = gobjects(5, 1);
            app.h_wind(1) = plot(app.Ax, nan, nan, '-k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            app.h_wind(2) = plot(app.Ax, nan, nan, '--k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            app.h_wind(3) = plot(app.Ax, nan, nan, '--k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            app.h_wind(4) = plot(app.Ax, nan, nan, '--k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            app.h_wind(5) = plot(app.Ax, nan, nan, '--k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            set(app.h_wind, 'visible', app.CallingApp.PlotVis_Win.Value)
            
            % Plot dummy data - cursor
            app.h_cursor = gobjects(1);
            ec = app.CallingApp.plotdata.h_optpoint.MarkerEdgeColor;
            fc = app.CallingApp.plotdata.h_optpoint.MarkerFaceColor;
            ms = app.CallingApp.plotdata.h_optpoint.MarkerSize;
            lw = app.CallingApp.plotdata.h_optpoint.LineWidth;
            app.h_cursor = plot(app.Ax, nan, nan, '+k', 'markerfacecolor', fc, 'markeredgecolor', ec, 'markersize', ms, 'linewidth', lw);
            
            % Create legend
            app.Lg = uiaxes(app.Figure, 'Units', 'pixels', 'Position', [95 135 420 70]);
            app.Lg.Visible = 'on';
            app.Lg.NextPlot = 'add';
            fill(app.Lg, [0.1 0.2 0.2 0.1], [0.65 0.65 0.95 0.95], 'r', 'FaceColor', [0 0.8 0], 'FaceAlpha', 0.5, 'EdgeColor', 'none')
            plot(app.Lg, 0.15, 0.42, '+k', 'markerfacecolor', fc, 'markeredgecolor', ec, 'markersize', ms, 'linewidth', lw);
            plot(app.Lg, [0.10 0.20], 0.09 * [1 1], '-k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            plot(app.Lg, [0.10 0.20], 0.04 * [1 1], '--k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            plot(app.Lg, [0.10 0.20], 0.14 * [1 1], '--k', 'color', app.CallingApp.colors.win, 'linewidth', 1.25);
            text(app.Lg, 0.22, 0.80, 'Permissible Region', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle');
            text(app.Lg, 0.22, 0.42, 'Selected Cursor Position', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle');
            text(app.Lg, 0.22, 0.09, 'Optimal region relative to specified wind direction', 'color', 'k', 'fontname', 'arial', 'fontsize', 15, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle');
            app.Lg.XLim = [0 1];
            app.Lg.YLim = [0 1];
            app.Lg.XTick = [];
            app.Lg.YTick = [];
            app.Lg.YDir = 'normal';
            app.Lg.XColor = 'none';
            app.Lg.YColor = 'none';
            app.Lg.Color = 'none';
            app.Lg.Box = 'on';
            
            % Create plot
            event.Source = app.Standoff_Data;
            Plot_Create(app, event)
        end

        % Value changed function: Stack_Data, Standoff_Data, 
        % Thresh_Data
        function Plot_Create(app, event)
            % Plot the data
            
            % Get required grid
            plotlim = 5 + 1.145 * app.Standoff_Data.Value;
            [xg, yg] = meshgrid(linspace(-plotlim, plotlim, 1001), linspace(-plotlim, plotlim, 1001));
            
            switch event.Source
                case {app.Stack_Data, app.Standoff_Data}
                    % Recompute position and pointing
                    app.RadMap = sqrt(xg .^ 2 + yg .^ 2);
                    app.BetMap = atand(app.Stack_Data.Value ./ app.RadMap);
                    app.AziMap = 180 + atan2d(xg, yg);
                    
                    % Update ordinate plots
                    app.h_ord(1).XData = [-plotlim plotlim];
                    app.h_ord(1).YData = [0 0];
                    app.h_ord(2).XData = [0 0];
                    app.h_ord(2).YData = [-plotlim plotlim];
                    
                    % Update ordinate text
                    app.h_ordtext(1).Position = [0, +plotlim * 1.02];
                    app.h_ordtext(2).Position = [+plotlim * 1.02, 0];
                    app.h_ordtext(3).Position = [0, -plotlim * 1.02];
                    app.h_ordtext(4).Position = [-plotlim * 1.02, 0];
                    
                    % Update radii
                    ds = 1;
                    dr = ceil(app.Standoff_Data.Value / 6 / 5) * 5;
                    for r = dr : dr : plotlim
                        nm = ceil(dr * 2 * pi / ds);
                        tt = linspace(0, 360, nm);
                        app.h_rad(round(r / dr)).XData = r * sind(tt);
                        app.h_rad(round(r / dr)).YData = r * cosd(tt);
                        app.h_radtext(round(r / dr)).String = num2str(r, '%u');
                        app.h_radtext(round(r / dr)).Position = r * [sind(45) cosd(45)];
                    end
                    
                case app.Thresh_Data
                    % Update calling app
                    app.CallingApp.IntSlide_Cont.Value = event.Value;
                    app.CallingApp.sub_IntValueChanged_FromSubClass(struct('Source', app.CallingApp.IntSlide_Cont, 'Value', event.Value))
            end
            assignin('base', 'h', app)
            
            % Get uncertainty data
            data.asN = app.CallingApp.plotdata.h_image.XData;
            data.bc = app.CallingApp.plotdata.h_image.YData;
            data.unc = app.CallingApp.plotdata.h_image.CData;
            
            % Obtain contours
            data.good = interp2(data.asN, data.bc, data.unc, app.AziMap, app.BetMap, 'linear', nan) < app.Thresh_Data.Value;
            data.good(app.RadMap > app.Standoff_Data.Value) = 0;
            
            % Show region
            app.h_image.XData = xg(1, :);
            app.h_image.YData = yg(:, 1);
            app.h_image.CData = uint8(255 * cat(3, data.good * 0, data.good * 0.8, data.good * 0));
            app.h_image.AlphaData = single(data.good * 0.5);
            
            % Format axis
            axlim = plotlim * 1.15;
            set(app.Ax, 'xlim', axlim * [-1 1], 'xtick', [])
            set(app.Ax, 'ylim', axlim * [-1 1], 'ytick', [], 'ydir', 'normal')
            set(app.Ax, 'box', 'off', 'visible', 'on', 'dataaspectratio', [1 1 1])
            
            % Update wind orthogonal
            xx = plotlim * cosd(app.CallingApp.IntSlide_Wind.Value) * [-1 +1];
            yy = plotlim * sind(app.CallingApp.IntSlide_Wind.Value) * [+1 -1];
            app.h_wind(1).XData = xx;
            app.h_wind(1).YData = yy;
            
            % Update wind bounds for 5% OOPM
            for idx = 1 : 4
                tt = app.CallingApp.plotdata.h_winderr(idx).XData(1);
                xx = [0, plotlim * sind(tt)];
                yy = [0, plotlim * cosd(tt)];
                app.h_wind(idx + 1).XData = xx;
                app.h_wind(idx + 1).YData = yy;
            end
            set(app.h_wind, 'visible', app.CallingApp.PlotVis_Win.Value)
            
            % Update cursor position
            azi = app.CallingApp.Selected_Azi.Value;
            bet = app.CallingApp.Selected_Inc.Value;
            rr = app.Stack_Data.Value / tand(bet);
            xx = rr * -sind(azi);
            yy = rr * -cosd(azi);
            app.h_cursor.XData = xx;
            app.h_cursor.YData = yy;
            
        end

        % Button pushed function: Button_SaveToKML
        function SaveToKML(app, event)
            % This callback save the permissible region data to a KML file
            % for interfacing with Google Earth
            
            % Obtain position of flare stack
            stack.lat = app.CallingApp.Loc_Lat.Value .* (-1) ^ (str2double(app.CallingApp.Loc_LatHemi.Value) + 1);
            stack.lon = app.CallingApp.Loc_Lon.Value .* (-1) ^ (str2double(app.CallingApp.Loc_LonHemi.Value) + 1);
            
            % Compute boundaries
            bin = ~~sum(app.h_image.CData, 3);
            bnd = bwboundaries(bin, 8);
            
            % Get position relative to stack
            xx = cellfun(@(x) app.h_image.XData(x(:, 2)), bnd, 'uni', 0);
            yy = cellfun(@(x) app.h_image.YData(x(:, 1)), bnd, 'uni', 0);
            
            % Get distance and azimuth
            rad = cellfun(@(x, y) sqrt(x(:) .^ 2 + y(:) .^ 2), xx, yy, 'uni', 0);
            azi = cellfun(@(x, y) 180 + atan2d(-x(:), -y(:)), xx, yy, 'uni', 0);
            
            % Compute coordinates of regions
            [lat, lon] = cellfun(@(r, a) Vincenty_Forward(app, stack.lat, stack.lon, r, a),  rad, azi, 'uni', 0);
            
            % Compute polygons for KML file =========================
            
            % Get component labels and boundaries
            ccL = bwlabel(bin, 8);
            
            % Create boundary parent tracking
            bnd_parent = zeros(length(bnd), 1) + nan;
            
            % Cycle through boundaries, identifying to which object they belong
            for bndidx = 1 : length(bnd)
                tmpx = bnd{bndidx}(:, 2);
                tmpy = bnd{bndidx}(:, 1);
                tmpI = zeros(size(ccL));
                tmpI(sub2ind(size(tmpI), tmpy, tmpx)) = 1;
                tmpI = imdilate(tmpI, ones(3));
                [row, col] = find(tmpI);
                labs = ccL(sub2ind(size(ccL), row, col));
                [labs, ~, idx] = unique(labs);
                cnts = accumarray(idx, 1);
                cnts = cnts(labs > 0);
                labs = labs(labs > 0);
                bnd_parent(bndidx) = labs((cnts / size(bnd{bndidx}, 1)) > 0.01);
            end
            
            % Determine boundary types
            % Create boundary cell
            lats = cell(max(ccL(:)), 1);
            lons = cell(max(ccL(:)), 1);
            holes = lats;
            
            for objidx = 1 : size(lats, 1)
                % Pull containing bounds
                lats{objidx} = lat(bnd_parent == objidx)';
                lons{objidx} = lon(bnd_parent == objidx)';
                
                % If only one boundary, define as outer!
                if length(lats{objidx}) == 1
                    holes{objidx} = false;
                    continue
                end
                
                % Otherwise, determine outer vs. inner
                % Create polyshape from bounds
                xdat = cellfun(@(x) x, lons{objidx}, 'uni', 0)';
                ydat = cellfun(@(x) x, lats{objidx}, 'uni', 0)';
                p = polyshape(xdat, ydat);
                
                % Determine if bound is a hole
                holes{objidx} = ishole(p)';
            end
            
            % Prepare KML =========================
            
            % Load KML template
            fSOURCE = fopen(fullfile(app.CallingApp.folder_base, 'KML_Template.kml'));
            
            % Write KML
            [fil, fol] = uiputfile('*.kml'); 
            savefile = fullfile(fol, fil);
            if fil == 0
                fclose(fSOURCE);
                return
            end
            fDESTIN = fopen(savefile, 'wt');
            while ~feof(fSOURCE)
                % Get line
                str = fgetl(fSOURCE);
                
                % Parse what to do
                if contains(str, '###Insert Flare Stack Coordinates (csv)###')
                    fprintf(fDESTIN, '          %.10f,%.10f\n', stack.lon, stack.lat);
                    
                elseif contains(str, '###Insert Flare Stack Longitude###')
                    fprintf(fDESTIN, '        %.10f\n', stack.lon);
                    
                elseif contains(str, '###Insert Flare Stack Latitude###')
                    fprintf(fDESTIN, '        %.10f\n', stack.lat);
                    
                elseif contains(str, '###Insert Camera Height###')
                    fprintf(fDESTIN, '        %u\n', app.Standoff_Data.Value * 4);
                    
                elseif contains(str, '###Insert Permissible Regions###')
                    for objidx = 1 : length(lats) % For each region
                        % Write header
                        fprintf(fDESTIN, '    <Placemark>\n');
                        fprintf(fDESTIN, '      <name>\n');
                        fprintf(fDESTIN, '        Permissible camera location: Uncertainty = %.1f%%\n', app.Thresh_Data.Value);
                        fprintf(fDESTIN, '      </name>\n');
                        fprintf(fDESTIN, '      <styleUrl>\n');
                        fprintf(fDESTIN, '        #transparentGreen\n');
                        fprintf(fDESTIN, '      </styleUrl>\n');
                        fprintf(fDESTIN, '      <Polygon>\n');
                        fprintf(fDESTIN, '        <altitudeMode>\n');
                        fprintf(fDESTIN, '          relativeToGround\n');
                        fprintf(fDESTIN, '        </altitudeMode>\n');
                        
                        % Cycle through objects
                        for bndidx = 1 : length(holes{objidx})
                            if holes{objidx}(bndidx)
                                fprintf(fDESTIN, '        <innerBoundaryIs>\n');
                            else
                                fprintf(fDESTIN, '        <outerBoundaryIs>\n');
                            end
                            fprintf(fDESTIN, '          <LinearRing>\n');
                            fprintf(fDESTIN, '            <coordinates>\n');
                            for coord = 1 : size(lats{objidx}{bndidx}, 1)
                                fprintf(fDESTIN, '              %.10f,%.10f,%u\n', lons{objidx}{bndidx}(coord), lats{objidx}{bndidx}(coord), app.Stack_Data.Value);
                            end
                            fprintf(fDESTIN, '            </coordinates>\n');
                            fprintf(fDESTIN, '          </LinearRing>\n');
                            if holes{objidx}(bndidx)
                                fprintf(fDESTIN, '        </innerBoundaryIs>\n');
                            else
                                fprintf(fDESTIN, '        </outerBoundaryIs>\n');
                            end
                        end
                        
                        % Write footer
                        fprintf(fDESTIN, '      </Polygon>\n');
                        fprintf(fDESTIN, '    </Placemark>\n');
                        
                    end
                    
                elseif contains(str, '###Insert Permissible Region Fill###')
                    % Write header
                    fprintf(fDESTIN, '    <GroundOverlay>\n');
                    fprintf(fDESTIN, '      <Icon>\n');
                    fprintf(fDESTIN, '        Permissible camera location: Uncertainty = %.1f%%\n', app.Thresh_Data.Value);
                    fprintf(fDESTIN, '      </Icon>\n');
                    fprintf(fDESTIN, '      <styleUrl>\n');
                    fprintf(fDESTIN, '        #yellowLine\n');
                    fprintf(fDESTIN, '      </styleUrl>\n');
                    fprintf(fDESTIN, '      <LineString>\n');
                    fprintf(fDESTIN, '        <altitudeMode>\n');
                    fprintf(fDESTIN, '          clamptoGround\n');
                    fprintf(fDESTIN, '        </altitudeMode>\n');
                    fprintf(fDESTIN, '        <coordinates>\n');
                    
                    % Write coordinates
                    for coord = 1 : length(lat{reg})
                        fprintf(fDESTIN, '          %.10f,%.10f\n', lon{reg}(coord), lat{reg}(coord));
                    end
                    
                    % Write footer
                    fprintf(fDESTIN, '        </coordinates>\n');
                    fprintf(fDESTIN, '      </LineString>\n');
                    fprintf(fDESTIN, '    </Placemark>\n');
                    
                else % Just copy
                    fprintf(fDESTIN, [str, '\n']);
                end
            end
            fclose('all');
            
            % Try to open google earth
            if ispc
                loc = evalc('!where /r "C:\Program Files\Google" googleearth.exe');
                if exist(loc(1 : end - 1), 'file')
                    evalc(sprintf('!"%s" -o %s &', loc(1 : end -1), savefile));
                end
            end
        end

        % Close request function: Figure
        function Figure_CloseFcn(app, event)
            % This callback re-enables the calling button and closes the
            % app
            uiresume(app.CallingApp.Figure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Permissible Camera Locations';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @Figure_CloseFcn, true);
            app.Grid_Main = uigridlayout(app.Figure);
            app.Panel_Inputs = uipanel(app.Grid_Main);
            app.Grid_Inputs = uigridlayout(app.Panel_Inputs);
            app.Stack_Label = uilabel(app.Grid_Inputs);
            app.Stack_Label.Text = 'Flare Stack Height:';
            app.Standoff_Label = uilabel(app.Grid_Inputs);
            app.Standoff_Label.Text = 'Maximum Stand-off Distance:';
            app.Thresh_Label = uilabel(app.Grid_Inputs);
            app.Thresh_Label.Text = 'Uncertainty Statistic Threshold:';
            app.Stack_Data = uispinner(app.Grid_Inputs);
            app.Stack_Data.Limits = [0 250];
            app.Stack_Data.RoundFractionalValues = 'on';
            app.Stack_Data.ValueDisplayFormat = '%u m';
            app.Stack_Data.ValueChangedFcn = createCallbackFcn(app, @Plot_Create, true);
            app.Stack_Data.Value = 10;
            app.Standoff_Data = uispinner(app.Grid_Inputs);
            app.Standoff_Data.Limits = [25 1000];
            app.Standoff_Data.RoundFractionalValues = 'on';
            app.Standoff_Data.ValueDisplayFormat = '%u m';
            app.Standoff_Data.ValueChangedFcn = createCallbackFcn(app, @Plot_Create, true);
            app.Standoff_Data.Value = 100;
            app.Thresh_Data = uispinner(app.Grid_Inputs);
            app.Thresh_Data.Step = 0.1;
            app.Thresh_Data.Limits = [15 60];
            app.Thresh_Data.ValueDisplayFormat = '%.1f%%';
            app.Thresh_Data.ValueChangedFcn = createCallbackFcn(app, @Plot_Create, true);
            app.Thresh_Data.Value = 16;
            app.Grid_Main_Label = uigridlayout(app.Grid_Main);
            app.Grid_Main_Title = uilabel(app.Grid_Main_Label);
            app.Grid_Main_Title.Text = 'Permissible Camera Locations Relative to Flare Stack [m]';
            app.Grid_Main_Subtitle = uilabel(app.Grid_Main_Label);
            app.Grid_Main_Subtitle.Text = 'Statistic Name';
            app.Grid_SaveToKML = uigridlayout(app.Grid_Main);
            app.Button_SaveToKML = uibutton(app.Grid_SaveToKML, 'push');
            app.Button_SaveToKML.ButtonPushedFcn = createCallbackFcn(app, @SaveToKML, true);
            app.Button_SaveToKML.Text = 'Save to .KML file';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetupSkyLOSA_PlotPermissibleLocation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)Figure_StartFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end